classdef Ca_Data_Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ImageAxes                    matlab.ui.control.UIAxes
        LoadButton                   matlab.ui.control.Button
        ImagingChannelDropDownLabel  matlab.ui.control.Label
        ImagingChannelDropDown       matlab.ui.control.DropDown
        DataTypeDropDownLabel        matlab.ui.control.Label
        DataTypeDropDown             matlab.ui.control.DropDown
        PrimaryCndNoEditFieldLabel   matlab.ui.control.Label
        PrimaryCndNoEditField        matlab.ui.control.NumericEditField
        ImagingDataPanel             matlab.ui.container.Panel
    end

    
    properties (Access = public)
        experimentStructure
        cmap
        cellNum
        numberTextHandle
        zoomLevel
        patchCenter
        secondaryCndNo
        data2plot
        imageAxisPosOrigin
    end
    
    methods (Access = private)
        
        function loadImage(app)
            
            % load in average image
            try
                displayIm = read_Tiffs([app.experimentStructure.savePath 'STD_Stim_Sum.tif']);
            catch
                displayIm = read_Tiffs([app.experimentStructure.savePath 'STD_Stim_Sum_Ch' app.ImagingChannelDropDown.Value '.tif']);
            end
            
            figure(app.UIFigure);
            
            createPatchFigApp(app, displayIm);
        end
        
        function createPatchFigApp(app, displayIm)
            % find number of ROIs
            
            imageROI = app.experimentStructure.labeledCellROI;
            numROIs = max(imageROI(:));
            boundaries = cell(numROIs,1);
            %iterate through ROI number to get them in appropriate order
            for i = 1: numROIs
                tempImageROI = imageROI;
                tempImageROI(tempImageROI~=i) = 0;
                tempBounds = bwboundaries(tempImageROI, 4, 'noholes');
                boundaries(i,1) =tempBounds(1);
            end
            % sets color map for the cell ROIs
            
            cmap = distinguishable_colors(numROIs+1,'w');
            % cmap = linspecer(numROIs+1, 'qualitative');
            % cmap = cmap(randperm(size(cmap,1)),:);
            cmap(1,:) = [0,0,0];
            app.cmap = cmap;
            
            imagesc(app.ImageAxes,displayIm);
            % build patch structure
            
            for i =1:numROIs
                patch(app.ImageAxes,boundaries{i,1}(:,2), boundaries{i,1}(:,1), app.cmap(i+1, :), 'FaceAlpha', 0.3, ...
                    'ButtonDownFcn', @(src,evnt)plotMouseClickvApp(app,src,evnt));
            end
            
            % check to get primary cnd number
            if isfield(app.experimentStructure, 'stimParams')
                
                try
                    app.PrimaryCndNoEditField.Value = length(unique(app.experimentStructure.stimParams.Angle));
                catch ME
                    app.PrimaryCndNoEditField.Value = length(app.experimentStructure.stimParams.levelStim);
                end
            end
            
            app.secondaryCndNo = length(app.experimentStructure.cndTotal)/app.PrimaryCndNoEditField.Value;
            
            app.imageAxisPosOrigin = app.ImageAxes.Position;
            panelPos = app.ImagingDataPanel.Position;
            
            app.ImagingDataPanel.Position = [panelPos(1)* 0.94 app.imageAxisPosOrigin(2) app.imageAxisPosOrigin(3)* 0.75 app.imageAxisPosOrigin(4)];
            
            
        end
        
        function plotMouseClickvApp(app,src,evnt)
            % RETRIEVE CLICK LOCATION AND DATA TYPE
            cp = fliplr(evnt.IntersectionPoint(1:2));
            clickPoint = floor(cp);
            
            
            switch evnt.Button
                
                case 1 % Left Button PLOT SINGLE TRACE
                    
                    % figure out which cell the click was on
                    indxClick = sub2ind([app.experimentStructure.pixelsPerLine app.experimentStructure.linesPerFrame], clickPoint(1), clickPoint(2));
                    
                    app.cellNum = app.experimentStructure.labeledCellROI(indxClick);
                    
                    if app.cellNum == 0
                        app.cellNum = 1;
                    end
            end
            
            plotCaTracesApp(app);
            addCellNumberLabel(app);
            
        end
        
        function plotCaTracesApp(app)
            delete(app.ImagingDataPanel.Children)
            
            switch  app.DataTypeDropDown.Value
                
                case 'FBS: Mean Cnd'
                    app.ImagingDataPanel.AutoResizeChildren = 'off';
                    
                    app.data2plot = app.experimentStructure.dFperCndMeanFBS{app.cellNum};
                    
                    plotCndTraces(app);
                    
                case 'Raw'
                    app.data2plot = app.experimentStructure.rawF(app.cellNum,:);
                    
                    activityAxis = axes(app.ImagingDataPanel);
                    plot(activityAxis,1:length(app.data2plot),app.data2plot, 'Color',app.cmap(app.cellNum+1, :));
                
                    activityAxis.YLim = [min(app.data2plot) - 100 ; max(app.data2plot) + 100  ];
                    activityAxis.XLim = [0 length(app.data2plot)];
                    activityAxis.XTick = [0:250:length(app.data2plot) ];
                    activityAxis.XTickLabel = [0:250:length(app.data2plot) ];
                    activityAxis.Title.String = 'Raw F';
                    activityAxis.YLabel.String = 'Intensity';
                    activityAxis.XLabel.String = 'Frame No.';
                    
                case 'Neuropil Corrected: Mean Cnd'
                    app.data2plot = app.experimentStructure.dFperCndMean{app.cellNum};
                    plotCndTraces(app);
                    
                case 'Neuropil Corrected: dF/F'
                    app.data2plot = app.experimentStructure.dF(app.cellNum,:);
                    
                    activityAxis = axes(app.ImagingDataPanel);
                    plot(activityAxis,1:length(app.data2plot),app.data2plot, 'Color',app.cmap(app.cellNum+1, :));
                    
                    activityAxis.YLim = [min(app.data2plot) - 0.1 ; max(app.data2plot) + 0.1  ];
                    activityAxis.XLim = [0 length(app.data2plot)];
                    activityAxis.XTick = [0:250:length(app.data2plot) ];
                    activityAxis.XTickLabel = [0:250:length(app.data2plot) ];
                    activityAxis.Title.String = 'Delta F/F Trace';
                    activityAxis.YLabel.String = 'Delta F/F';
                    activityAxis.XLabel.String = 'Frame No.';
            end
        end
        
        function addCellNumberLabel(app)
            delete(app.numberTextHandle);
            % plots new cell number
            app.numberTextHandle = handle(text(...
                'String', sprintf('%i',app.cellNum),...
                'FontSize', 20, ...
                'FontWeight','bold',...
                'BackgroundColor',[.1 .1 .1 .3],...
                'Margin',1,...
                'Position', round([app.experimentStructure.xPos(app.cellNum) app.experimentStructure.yPos(app.cellNum)]) - [0 10],...
                'Parent', app.ImageAxes,...
                'Color',app.cmap(app.cellNum+1, :)));
            
            % if the magnifcation factor is greater than 1, then shifts cell
            % image view to centralize current cell
            if app.zoomLevel >1
                shiftImageView(app)
            end
        end
        
        function magnifyImage(app)
            if app.zoomLevel >1
                app.patchCenter = [app.experimentStructure.xPos(app.cellNum) app.experimentStructure.yPos(app.cellNum)];
                newBoundary = round(app.experimentStructure.pixelsPerLine/ app.zoomLevel); % sets new frame size
                
                app.ImageAxes.YLim = [(app.patchCenter(2)-newBoundary) (app.patchCenter(2)+newBoundary)];
                app.ImageAxes.XLim = [(app.patchCenter(1)-newBoundary) (app.patchCenter(1)+newBoundary)];
            else
                app.ImageAxes.YLim = [0.5 app.experimentStructure.pixelsPerLine];
                app.ImageAxes.XLim = [0.5 app.experimentStructure.pixelsPerLine];
            end
        end
        
        function shiftImageView(app)
            axisLims = app.ImageAxes.XLim; % gets current axis limits
            newBoundary = round((axisLims(2)-axisLims(1))/2); % gets current view size in pixels
            
            % sets new axis size around patch center
            app.ImageAxes.XLim = [app.experimentStructure.xPos(app.cellNum)-newBoundary app.experimentStructure.xPos(app.cellNum)+newBoundary];
            app.ImageAxes.YLim = [app.experimentStructure.yPos(app.cellNum)-newBoundary app.experimentStructure.yPos(app.cellNum)+newBoundary];
        end
        
        function   plotCndTraces(app)
            
            lengthOfData = app.experimentStructure.meanFrameLength;
            prev2ndDim = 0;
            % plotting
            for x =1:size(app.data2plot, 2)
                
                % get current second dimension number
                current2ndDim = (x/app.PrimaryCndNoEditField.Value);
                if floor(current2ndDim)~=current2ndDim
                    current2ndDim = ceil(current2ndDim);
                end
                
                % get the spacings and labels for the traces
                if current2ndDim< 2
                    spacing = 5;
                    xlocations(x,:) = ((lengthOfData +lengthOfData* (x-1))- (lengthOfData-1) :lengthOfData + lengthOfData* (x-1)) + spacing*(x-1);
                    xlocationMid(x) = xlocations(x,round(lengthOfData/2));
                else
                    currentOrientation =  x- ((current2ndDim-1)*app.PrimaryCndNoEditField.Value);
                    xlocations(x,:) = xlocations(currentOrientation,:);
                end
                
                OSI_text =[];
                if isfield(app.experimentStructure,'OSI_FBS')
                    OSI_text = num2str(app.experimentStructure.OSI_FBS(app.cellNum));
                end
                
                % set axis labels if on new 2nd dim axis
                if current2ndDim > prev2ndDim
                    
                    subFighandle(current2ndDim) = subplot(app.secondaryCndNo,1,current2ndDim, 'Parent', app.ImagingDataPanel);
                    hold(subFighandle(current2ndDim), 'on');
                    
                    subFighandle(current2ndDim).Title.String = [app.DataTypeDropDown.Value '  OSI: ' OSI_text ];
                    subFighandle(current2ndDim).YLabel.String = '\DeltaF/F';
                    subFighandle(current2ndDim).XLabel.String = 'Cnd No.';
                end
                
                lineCol = app.cmap(app.cellNum+1, :);
                errorBars = app.experimentStructure.dFperCndSTDFBS{app.cellNum}(:,x);
                shadedErrorBarV2(xlocations(x,:),app.data2plot(:,x)',errorBars, 'lineprops', {'-','Color', lineCol, 'LineWidth', 2},'Axis', subFighandle(current2ndDim));
                
                subFighandle(current2ndDim).XLim = [0, max(xlocations(:))];
                subFighandle(current2ndDim).XTick = xlocationMid;
                subFighandle(current2ndDim).XTickLabel = [1: app.PrimaryCndNoEditField.Value];
                
                yMaxVector(x) =  max(app.data2plot(:,x)+errorBars);
                yMinVector(x) =  min(app.data2plot(:,x)-errorBars);
                
                prev2ndDim = current2ndDim;
            end
            
            
            xLinesStimON = xlocations(1:app.PrimaryCndNoEditField.Value,1) + app.experimentStructure.stimOnFrames(1)-1;
            xLinesStimOFF = xlocations(1:app.PrimaryCndNoEditField.Value,1) + app.experimentStructure.stimOnFrames(2)-1;
            xLines = [xLinesStimON;xLinesStimOFF];
            
            for q = 1:current2ndDim
                subFighandle(q).YLim = [min(yMinVector) max(yMaxVector)];
                vline2(subFighandle(q), xLines, 'k--');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % maximise screen size
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
            
            
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.ImagingChannelDropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Display uigetfile dialog
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                load([p f]);
                app.experimentStructure = experimentStructure;
                loadImage(app);
            end
        end

        % Value changed function: ImagingChannelDropDown
        function ImagingChannelDropDownValueChanged(app, event)
            app.ImagingChannelDropDown.Value;
            loadImage(app);
            
            
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            value = app.DataTypeDropDown.Value;
            plotCaTracesApp(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
            if isempty(app.cellNum)
                app.cellNum = 1;
            end
            
            switch event.Key
                case 'space' % CHOOSE TRACES TO PLOT
                    % Sets up dialog box
                    prompt = {'Enter desired trace numbers:'};
                    dlg_title = 'Trace No. Inputs';
                    num_lines = 1;
                    defaultans = {'1'};
                    answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
                    answer = str2num(answer{1,1});
                    app.cellNum = answer;
                    
                    plotCaTracesApp(app);
                    addCellNumberLabel(app);
                case 'leftarrow' % CYCLE DOWN THROUGH TRACES
                    app.cellNum=app.cellNum-1; % iterates down one cell
                    
                    if app.cellNum<1 % unless cell is 1
                        app.cellNum= 1;
                    end
                    
                    plotCaTracesApp(app);
                    addCellNumberLabel(app);
                    
                case 'rightarrow' %CYCLE UP THROUGH TRACES
                    app.cellNum=app.cellNum+1; % iterates down one cell
                    
                    if app.cellNum >=app.experimentStructure.cellCount% unless cell is 1
                        app.cellNum= 1;
                    end
                    
                    plotCaTracesApp(app);
                    addCellNumberLabel(app);
                    
                    
                case 'uparrow' %INCREASE IMAGE ZOOM
                    app.zoomLevel = 3;
                    magnifyImage(app);
                case 'downarrow' % DECREASE IMAGE ZOOM
                    app.zoomLevel = 1;
                    magnifyImage(app);
            end
        end

        % Value changed function: PrimaryCndNoEditField
        function PrimaryCndNoEditFieldValueChanged(app, event)
            app.PrimaryCndNoEditField.Value;
            app.secondaryCndNo = length(app.experimentStructure.cndTotal)/app.PrimaryCndNoEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Experiment Structure';
            app.ImagingChannelDropDownLabel = uilabel(app.UIFigure);
            app.ImagingChannelDropDownLabel.Text = 'Imaging Channel ';
            app.ImagingChannelDropDown = uidropdown(app.UIFigure);
            app.ImagingChannelDropDown.Items = {'1', '2'};
            app.ImagingChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagingChannelDropDownValueChanged, true);
            app.ImagingChannelDropDown.Value = '2';
            app.DataTypeDropDownLabel = uilabel(app.UIFigure);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.UIFigure);
            app.DataTypeDropDown.Items = {'FBS: Mean Cnd', 'Raw', 'Neuropil Corrected: Mean Cnd', 'Neuropil Corrected: dF/F'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'FBS: Mean Cnd';
            app.PrimaryCndNoEditFieldLabel = uilabel(app.UIFigure);
            app.PrimaryCndNoEditFieldLabel.Text = 'Primary Cnd No.';
            app.PrimaryCndNoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrimaryCndNoEditField.ValueChangedFcn = createCallbackFcn(app, @PrimaryCndNoEditFieldValueChanged, true);
            app.PrimaryCndNoEditField.Value = 8;
            app.ImagingDataPanel = uipanel(app.UIFigure);
            app.ImagingDataPanel.Title = 'Imaging Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ca_Data_Viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end