classdef FayeDataApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        DatAx                  matlab.ui.container.Panel
        RecalpeaksButton       matlab.ui.control.Button
        LoadDataButton         matlab.ui.control.Button
        DataTypeDropDown       matlab.ui.control.DropDown
        DataTypeDropDownLabel  matlab.ui.control.Label
        ImAx                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        exStruct % experiment structure
        cellNum
        data2plot
        patchCenter
        cmap
        zoomLevel
        numberTextHandle
    end
    
    methods (Access = private)
        
        function results = loadImage(app)
            % loads in average image
            displayIm = imadjust(read_Tiffs([app.exStruct.filePath(1:end-13) '_SD.tif']));


            figure(app.UIFigure);

            % creates patch objects
            createPatchFigApp(app, displayIm);
        end


        function createPatchFigApp(app, displayIm)
            % creats patch ROIs ontop of image

             % find number of ROIs
    
            imageROI = app.exStruct.cells.labeledCellROI;
            numROIs = max(imageROI(:));
            boundaries = cell(numROIs,1);

            %iterate through ROI number to get them in appropriate order
            for i = 1: numROIs
                tempImageROI = imageROI;
                tempImageROI(tempImageROI~=i) = 0;
                tempBounds = bwboundaries(tempImageROI, 4, 'noholes');
                boundaries(i,1) =tempBounds(1);
            end
            % sets color map for the cell ROIs
            cmap = distinguishable_colors(numROIs+1,{'w','k'});
            cmap(1,:) = [0,0,0];
            app.cmap = cmap;
            
            imagesc(app.ImAx,displayIm);
            % build patch structure
            
            for i =1:numROIs
                patch(app.ImAx,boundaries{i,1}(:,2), boundaries{i,1}(:,1), app.cmap(i+1, :), 'FaceAlpha', 0.3, ...
                    'ButtonDownFcn', @(src,evnt)plotMouseClickvApp(app,src,evnt));
            end
        end

        function plotMouseClickvApp(app,src,evnt)
            % RETRIEVE CLICK LOCATION AND DATA TYPE
            cp = fliplr(evnt.IntersectionPoint(1:2));
            clickPoint = floor(cp);


            switch evnt.Button
                case 1 % Left Button PLOT SINGLE TRACE

                    % figure out which cell the click was on
                    indxClick = sub2ind([app.exStruct.image.pixelNum app.exStruct.image.pixelNum], clickPoint(1), clickPoint(2));

                    app.cellNum = app.exStruct.cells.labeledCellROI(indxClick);

                    if app.cellNum == 0
                        app.cellNum = 1;
                    end
            end

            plotCaTracesApp(app);
            addCellNumberLabel(app);

        end

        function plotCaTracesApp(app)
            delete(app.DatAx.Children)

            lineCol = app.cmap(app.cellNum+1,:);
            activityAxis = axes(app.DatAx);
            
            hold(activityAxis, 'on');

            switch  app.DataTypeDropDown.Value
                
                case 'dF'
                    app.DatAx.AutoResizeChildren = 'off';
                    
                    app.data2plot = app.exStruct.cells.dF(app.cellNum,:);
                    plot(activityAxis,1:length(app.data2plot),app.data2plot, 'Color',app.cmap(app.cellNum+1, :));


%                     activityAxis.YLim = [min(app.data2plot) - 0.2 ; max(app.data2plot) + 0.3  ];
                    activityAxis.Title.String = 'dF/F';
                    activityAxis.YLabel.String = 'dF/F';
                    activityAxis.XLabel.String = 'Frame No.';

                case 'Raw'
                    app.data2plot = app.exStruct.cells.rawF(app.cellNum,:);
                    plot(activityAxis,1:length(app.data2plot),app.data2plot, 'Color',app.cmap(app.cellNum+1, :));

                    
                    activityAxis.YLim = [min(app.data2plot) - 100 ; max(app.data2plot) + 100  ];
                    activityAxis.Title.String = 'Raw F';
                    activityAxis.YLabel.String = 'Intensity';
                    activityAxis.XLabel.String = 'Frame No.';
                    
                case 'Neuropil Corr'
                    app.data2plot = app.exStruct.cells.correctedF(app.cellNum,:);
                    plot(activityAxis,1:length(app.data2plot),app.data2plot, 'Color',app.cmap(app.cellNum+1, :));

                                        
                    activityAxis.Title.String = 'Neuropil Corrected F';
                    activityAxis.YLabel.String = 'Corrected Fluor Intensity';
                    activityAxis.XLabel.String = 'Frame No.';
            end


            activityAxis.XLim = [0 length(app.data2plot)];
            activityAxis.XTick = [0:20:length(app.data2plot) ];
            activityAxis.XTickLabel = [0:20:length(app.data2plot)]/app.exStruct.rate;

            % add in peaks if they exist
            if isfield(app.exStruct, 'spikes')
                if ~isempty(app.exStruct.spikes.spikeLocs{app.cellNum})
                    spLoc = app.exStruct.spikes.spikeLocs{app.cellNum};
                    riseLoc = app.exStruct.spikes.riseTimeIndx{app.cellNum};
                    decayLoc = app.exStruct.spikes.decayTimeIndx{app.cellNum};


                    scatter(activityAxis, spLoc, app.data2plot(spLoc), 80, '*', LineWidth=2);
                    scatter(activityAxis, riseLoc, app.data2plot(riseLoc), 80, '^', LineWidth=2);
                    scatter(activityAxis, decayLoc, app.data2plot(decayLoc), 80, 'v', LineWidth=2);
                end
            end

            hold(activityAxis, 'off');
        end


        function addCellNumberLabel(app)
            delete(app.numberTextHandle);
            % plots new cell number
            app.numberTextHandle = handle(text(...
                'String', sprintf('%i',app.cellNum),...
                'FontSize', 20, ...
                'FontWeight','bold',...
                'BackgroundColor',[.1 .1 .1 .3],...
                'Margin',1,...
                'Position', round([app.exStruct.cells.xPos(app.cellNum) app.exStruct.cells.yPos(app.cellNum)]) - [0 10],...
                'Parent', app.ImAx,...
                'Color',app.cmap(app.cellNum+1, :)));
            
            % if the magnifcation factor is greater than 1, then shifts cell
            % image view to centralize current cell
            if app.zoomLevel >1
                shiftImageView(app)
            end
        end

        function magnifyImage(app)
            if app.zoomLevel >1
                app.patchCenter = [app.exStruct.cells.xPos(app.cellNum) app.exStruct.cells.yPos(app.cellNum)];
                newBoundary = round(app.exStruct.image.pixelNum/ app.zoomLevel); % sets new frame size
                
                app.ImAx.YLim = [(app.patchCenter(2)-newBoundary) (app.patchCenter(2)+newBoundary)];
                app.ImAx.XLim = [(app.patchCenter(1)-newBoundary) (app.patchCenter(1)+newBoundary)];
            else
                app.ImAx.YLim = [0.5 app.exStruct.image.pixelNum];
                app.ImAx.XLim = [0.5 app.exStruct.image.pixelNum];
            end
        end

        
        function shiftImageView(app)
            axisLims = app.ImAx.XLim; % gets current axis limits
            newBoundary = round((axisLims(2)-axisLims(1))/2); % gets current view size in pixels
            
            % sets new axis size around patch center
            app.ImAx.XLim = [app.exStruct.cells.xPos(app.cellNum)-newBoundary app.exStruct.cells.xPos(app.cellNum)+newBoundary];
            app.ImAx.YLim = [app.exStruct.cells.yPos(app.cellNum)-newBoundary app.exStruct.cells.yPos(app.cellNum)+newBoundary];
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % maximise screen size
%             screenSize = get(groot,'ScreenSize');
%             screenWidth = screenSize(3);
%             screenHeight = screenSize(4);
%             left = screenWidth*0.1;
%             bottom = screenHeight*0.1;
%             width = screenWidth*0.8;
%             height = screenHeight*0.8;
%             drawnow;
%             app.UIFigure.Position = [left bottom width height];


            % Configure image axes
            app.ImAx.Visible = 'off';
            app.ImAx.Colormap = gray(256);
            axis(app.ImAx, 'image');
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
             % Display uigetfile dialog
            filterspec = {'*.mat'};
            [f, p] = uigetfile(filterspec);
            
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                load([p f]);
                app.exStruct = exStruct;

                % overwrite filePath
                app.exStruct.filePath = [p f(1:end-4) '.tif'];
                loadImage(app);
            end
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            value = app.DataTypeDropDown.Value;
            plotCaTracesApp(app);
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
              if isempty(app.cellNum)
                app.cellNum = 1;
            end
            
            switch event.Key
                case 'space' % CHOOSE TRACES TO PLOT
                    % Sets up dialog box
                    prompt = {'Enter desired trace numbers:'};
                    dlg_title = 'Trace No. Inputs';
                    num_lines = 1;
                    defaultans = {'1'};
                    answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
                    answer = str2num(answer{1,1});
                    app.cellNum = answer;
                    
                    plotCaTracesApp(app);
                    addCellNumberLabel(app);
                case 'leftarrow' % CYCLE DOWN THROUGH TRACES
                    app.cellNum=app.cellNum-1; % iterates down one cell
                    
                    if app.cellNum<1 % unless cell is 1
                        app.cellNum= 1;
                    end
                    
                    plotCaTracesApp(app);
                    addCellNumberLabel(app);
                    
                case 'rightarrow' %CYCLE UP THROUGH TRACES
                    app.cellNum=app.cellNum+1; % iterates down one cell
                    
                    if app.cellNum >=app.exStruct.cells.cellCount% unless cell is 1
                        app.cellNum= 1;
                    end
                    
                    plotCaTracesApp(app);
                    addCellNumberLabel(app);
                    
                    
                case 'uparrow' %INCREASE IMAGE ZOOM
                    app.zoomLevel = 3;
                    magnifyImage(app);
                case 'downarrow' % DECREASE IMAGE ZOOM
                    app.zoomLevel = 1;
                    magnifyImage(app);
            end
            
        end

        % Button pushed function: RecalpeaksButton
        function RecalpeaksButtonPushed(app, event)
            app = spikeFinderCallback(app);
            plotCaTracesApp(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ImAx = uiaxes(app.UIFigure);
            zlabel(app.ImAx, 'Z')
            app.ImAx.XTick = [];
            app.ImAx.YTick = [];
            app.DataTypeDropDownLabel = uilabel(app.UIFigure);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.UIFigure);
            app.DataTypeDropDown.Items = {'dF', 'Raw', 'Neuropil Corr'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'dF';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load in Experiment Structure';
            app.RecalpeaksButton = uibutton(app.UIFigure, 'push');
            app.RecalpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @RecalpeaksButtonPushed, true);
            app.RecalpeaksButton.Text = 'Recal peaks';
            app.DatAx = uipanel(app.UIFigure);
            app.DatAx.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FayeDataApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end