classdef clusterAlignmentApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        KeepROIsCheckBox  matlab.ui.control.CheckBox
        saveInfoField     matlab.ui.control.EditField
        SaveButton        matlab.ui.control.Button
        ROIList           matlab.ui.control.ListBox
        Delete_ROI_Bt     matlab.ui.control.Button
        Add_ROI_Bt        matlab.ui.control.Button
        ListSelector2     wt.ListSelector
        ChannelsBG        matlab.ui.container.ButtonGroup
        Ch4B              matlab.ui.control.ToggleButton
        Ch3B              matlab.ui.control.ToggleButton
        Ch2B              matlab.ui.control.ToggleButton
        Ch1B              matlab.ui.control.ToggleButton
        OpacitySlider     matlab.ui.control.Slider
        FileSelector      wt.FileSelector
        imageAx           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        translatedPos % Description
        translatedRotation
        clusterIm % full image
        clusterImDisp % single colour channel
        dispChan
        dFIm
        imOpacity
        clusterIM_hndle
        ROIs % Description
        CurrentROIIndex
        ROIsVerts
        ROI_cols
        exStruct
    end

    methods (Access = private)

        function results = updateImagePos(app, posChange, rotChange)

            if ~isequal(posChange, [0 0])
                app.translatedPos = app.translatedPos + posChange;
            end

            app.translatedRotation = app.translatedRotation + rotChange;

            [rotatedIM, corSys] = translateImInApp(app);
            imagesc(app.imageAx,app.dFIm);
            hold(app.imageAx, 'on');
            app.clusterIM_hndle = imshow(rotatedIM, corSys, 'Parent', app.imageAx);
            set(app.clusterIM_hndle, 'AlphaData',  app.imOpacity)

            hold(app.imageAx, 'off');

            redrawROIs(app);
        end


        function [image, corrdinSys] = translateImInApp(app)
            Rdefault = imref2d(size(app.clusterImDisp)); % The default coordinate system used by imrotate
            tX = mean(Rdefault.XWorldLimits);
            tY = mean(Rdefault.YWorldLimits);
            tTranslationToCenterAtOrigin = [1 0 0; 0 1 0; -tX -tY,1];
            theta = app.translatedRotation;
            tRotation = [cosd(theta) -sind(theta) 0; sind(theta) cosd(theta) 0; 0 0 1];
            tTranslationBackToOriginalCenter = [1 0 0; 0 1 0; tX tY,1];
            tTranslocation = [1 0 0; 0 1 0; app.translatedPos(1) app.translatedPos(2),1];
            tformCenteredRotation = tTranslationToCenterAtOrigin*tRotation*tTranslationBackToOriginalCenter * tTranslocation;
            tformCenteredRotation = affine2d(tformCenteredRotation);
            [image,corrdinSys] = imwarp(app.clusterImDisp,tformCenteredRotation);

        end

        % Update the ROIList UI component
        function updateROIList(app)
            roiNames = cell(1, length(app.ROIs));
            for i = 1:length(app.ROIs)
                roiNames{i} = ['ROI ' num2str(i)];
            end
            app.ROIList.Items = roiNames;
        end

        function redrawROIs(app)
            for i = 1:length(app.ROIsVerts)
                app.ROIs{i} = drawpolygon(app.imageAx,'Position', app.ROIsVerts{i}, 'Color', app.ROI_cols(i,:),'Label', num2str(i));
                addlistener(app.ROIs{i},'ROIMoved',@app.roiUpdater);
            end
        end

        function app = roiUpdater(app,src,evt)

            app.ROIsVerts{app.CurrentROIIndex} = evt.CurrentPosition;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.translatedPos = [0 0];
            app.translatedRotation = 0;
            app.imOpacity = 0.5;
            app.dispChan = 1;

            app.ROI_cols = distinguishable_colors(10, {'w','k'});

            % center figure on startup
            movegui(app.UIFigure, 'center');
        end

        % Callback function: FileSelector
        function FileSelectorValueChanged(app, event)

            %% set up images
            filePath = app.FileSelector.Value;
            filePath = filePath{1};
            [folderString, nameString] = fileparts(filePath);

            % DF_SD
            DF_image = read_Tiffs([filePath(1:end-13) '_DF_SD.tif']);

            % cluster snap
            [~ ,retinaID] = regexp(filePath, 'P\d+_ret\d+', 'tokens', 'match');
            snapDir = dir(fullfile(folderString,[ '**\*' retinaID{:} '*snap*.nd2' ]));
            clustIm = readFLAMEData(fullfile(snapDir.folder,snapDir.name));

            app.clusterIm = clustIm;
            app.clusterImDisp = imadjust(clustIm(:,:,app.dispChan));

            % upscale DF image
            upScaleFactor = size(clustIm,1)/size(DF_image,1);
            DF_image = imresize(DF_image,upScaleFactor);
            DF_image = imadjust(DF_image);

            app.dFIm = DF_image;
            imagesc(app.imageAx,app.dFIm);
            hold(app.imageAx, 'on');
            app.clusterIM_hndle = imagesc(app.imageAx, app.clusterImDisp, "AlphaData",app.imOpacity);
            hold(app.imageAx, 'off');
            %app.imageAx.Children.ButtonDownFcn = app.imageAx.ButtonDownFcn;

            %% load in exStruct
            load(filePath)
            app.exStruct = exStruct;

            % check if previously processed and use those settings
            if isfield(app.exStruct, 'clusterInfo')
                app.translatedPos = app.exStruct.clusterInfo.XYShift;
                app.translatedRotation = app.exStruct.clusterInfo.Rotation;
                app.ROIsVerts = app.exStruct.clusterInfo.clusterPolys;

                updateImagePos(app,[0 0],0);
                updateROIList(app);
            else
                app.translatedPos = [0 0];
                app.translatedRotation = 0;

                if app.KeepROIsCheckBox.Value ~= 1
                    app.ROIs = [];
                    app.ROIsVerts = [];
                    app.ROIList.Items = {};
                    app.CurrentROIIndex = 0;
                end
                app.saveInfoField.Value = [''];
                updateImagePos(app,[0 0],0);

            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)

            key = event.Key;

            switch key

                case 'rightarrow' % move right
                    updateImagePos(app,[1 0],0);

                case 'leftarrow' % move left
                    updateImagePos(app,[-1 0],0);

                case 'uparrow'
                    updateImagePos(app,[0 -1],0);

                case 'downarrow'
                    updateImagePos(app,[0 1],0);

                case 'pageup'
                    updateImagePos(app,[0 0],1);

                case 'pagedown'
                    updateImagePos(app,[0 0],-1);

                    %                 case 'space'
                    %                     pan(app.clusterIM_hndle, 'toggle');
            end

        end

        % Value changed function: OpacitySlider
        function OpacitySliderValueChanged(app, event)
            value = app.OpacitySlider.Value;
            app.clusterIM_hndle.AlphaData =    value/100;
            app.imOpacity = value/100;
        end

        % Button pushed function: Add_ROI_Bt
        function Add_ROI_BtButtonPushed(app, event)

            currNumPoly = length(app.ROIs);
            currNumPoly = currNumPoly+1;

            currPoly = drawpolygon(app.imageAx, 'Color',app.ROI_cols(currNumPoly,:),'Label', num2str(currNumPoly));
            app.ROIs{end+1} = currPoly;
            addlistener(app.ROIs{end},'ROIMoved',@app.roiUpdater);
            app.ROIsVerts{end+1} = currPoly.Position;
            app.CurrentROIIndex = length(app.ROIs);
            updateROIList(app);
            app.ROIList.Value = app.ROIList.Items{app.CurrentROIIndex};

        end

        % Button pushed function: Delete_ROI_Bt
        function Delete_ROI_BtButtonPushed(app, event)

            if ~isempty(app.ROIs)
                delete(app.ROIs{app.CurrentROIIndex});
                app.ROIs(app.CurrentROIIndex) = [];
                app.ROIsVerts(app.CurrentROIIndex) = [];
                if app.CurrentROIIndex > length(app.ROIs)
                    app.CurrentROIIndex = length(app.ROIs);
                end
                updateROIList(app);

                if ~isempty(app.ROIList.Items)
                    app.ROIList.Value = app.ROIList.Items{length(app.ROIs)};
                end
            end
        end

        % Value changed function: ROIList
        function ROIListValueChanged(app, event)
            app.CurrentROIIndex = find(contains(app.ROIList.Items, app.ROIList.Value));

        end

        % Selection changed function: ChannelsBG
        function ChannelsBGSelectionChanged(app, event)
            bttnText = get( app.ChannelsBG.Buttons,'Text');
            selectedButton = app.ChannelsBG.SelectedObject;
            selectedChannel = find(contains(bttnText, selectedButton.Text));

            app.clusterImDisp = imadjust(app.clusterIm(:,:,selectedChannel));
            app.dispChan = selectedChannel;
            updateImagePos(app,[0 0],0);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            % build the save struct in exStruct

            app.exStruct.clusterInfo.XYShift = app.translatedPos;
            app.exStruct.clusterInfo.Rotation = app.translatedRotation;
            app.exStruct.clusterInfo.clusterPolys = app.ROIsVerts;

            % save
            exStruct = app.exStruct;
            save([app.exStruct.filePath(1:end-4) '_ExStruct.mat'], 'exStruct', '-v7.3');

            app.saveInfoField.Value = ['Saved at ' datestr(now)];
        end

        % Button down function: imageAx
        function imageAxButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.imageAx = uiaxes(app.UIFigure);
            app.imageAx.XLim = [0 2048];
            app.imageAx.YLim = [0 2048];
            app.imageAx.XTick = [];
            app.imageAx.YTick = [];
            app.imageAx.ZTick = [];
            app.imageAx.ButtonDownFcn = createCallbackFcn(app, @imageAxButtonDown, true);
            colormap(app.imageAx, 'gray')
            app.FileSelector = wt.FileSelector(app.UIFigure);
            app.FileSelector.ShowHistory = 'on';
            app.FileSelector.ValueChangedFcn = createCallbackFcn(app, @FileSelectorValueChanged, true);
            app.OpacitySlider = uislider(app.UIFigure);
            app.OpacitySlider.ValueChangedFcn = createCallbackFcn(app, @OpacitySliderValueChanged, true);
            app.OpacitySlider.Value = 50;
            app.ChannelsBG = uibuttongroup(app.UIFigure);
            app.ChannelsBG.SelectionChangedFcn = createCallbackFcn(app, @ChannelsBGSelectionChanged, true);
            app.ChannelsBG.Title = 'Channels';
            app.Ch1B = uitogglebutton(app.ChannelsBG);
            app.Ch1B.Text = 'Ch1';
            app.Ch1B.Value = true;
            app.Ch2B = uitogglebutton(app.ChannelsBG);
            app.Ch2B.Text = 'Ch2';
            app.Ch3B = uitogglebutton(app.ChannelsBG);
            app.Ch3B.Text = 'Ch3';
            app.Ch4B = uitogglebutton(app.ChannelsBG);
            app.Ch4B.Text = 'Ch4';
            app.ListSelector2 = wt.ListSelector(app.UIFigure);
            app.Add_ROI_Bt = uibutton(app.UIFigure, 'push');
            app.Add_ROI_Bt.ButtonPushedFcn = createCallbackFcn(app, @Add_ROI_BtButtonPushed, true);
            app.Add_ROI_Bt.Text = 'Add ROI';
            app.Delete_ROI_Bt = uibutton(app.UIFigure, 'push');
            app.Delete_ROI_Bt.ButtonPushedFcn = createCallbackFcn(app, @Delete_ROI_BtButtonPushed, true);
            app.Delete_ROI_Bt.Text = 'Delete ROI';
            app.ROIList = uilistbox(app.UIFigure);
            app.ROIList.Items = {};
            app.ROIList.ValueChangedFcn = createCallbackFcn(app, @ROIListValueChanged, true);
            app.ROIList.Value = {};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.saveInfoField = uieditfield(app.UIFigure, 'text');
            app.KeepROIsCheckBox = uicheckbox(app.UIFigure);
            app.KeepROIsCheckBox.Text = 'Keep ROIs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clusterAlignmentApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end