classdef waveTrackingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FPS                       matlab.ui.control.NumericEditField
        FPSEditFieldLabel         matlab.ui.control.Label
        PlayMovieButton           matlab.ui.control.StateButton
        LastSavedBox              matlab.ui.control.TextArea
        DistMovedFiltButton       matlab.ui.control.StateButton
        waveFilter                matlab.ui.control.Button
        saveButton                matlab.ui.control.Button
        SelectionTypeButtonGroup  matlab.ui.container.ButtonGroup
        OpticNerveButton          matlab.ui.control.RadioButton
        RetinalOutlineButton      matlab.ui.control.RadioButton
        VascularPlexusButton      matlab.ui.control.RadioButton
        WaveTrackingButton        matlab.ui.control.RadioButton
        RecatWaves                matlab.ui.control.Button
        LoadFileButton            matlab.ui.control.Button
        Slider                    matlab.ui.control.Slider
        SliderLabel               matlab.ui.control.Label
        imageWaveColAx            matlab.ui.control.UIAxes
        imageDFAx                 matlab.ui.control.UIAxes
    end


    properties (Access = private)

    end

    properties (Access = public)
        disStack % Description
        exStruct
        mergeStruct
        homeDir
        BV_Position
        BV_poly

        RetBound_poly
        RetBound_Position

        opticNerve_poly
        opticNerve_Position

        merge2Wave % wave number to merge everything into

    end

    methods (Access = private)

        function app = loadImages(app)

            exStruct = app.exStruct;

            % get filepath strings
            dF_filepath = [exStruct.filePath(1:end-4) '_dF_F.tif'];
            waveColFilepath = [exStruct.filePath(1:end-4) '_waveCol.tif'];


            % load in dF images and waveCol Images
            % disStack.dFTifStack = read_Tiffs(dF_filepath);
            disStack.dFTifStack = tiffreadVolume(dF_filepath);
            disStack.dFTifStack = imadjustn(disStack.dFTifStack);
            disStack.waveColStack = tiffreadVolume(waveColFilepath);

            % load in blood vessel images if exist
            % disStack.bloodVessels = read_Tiffs([exStruct.filePath(1:end-4) '_BV_SD.tif']);
            disStack.bloodVessels = tiffreadVolume([exStruct.filePath(1:end-4) '_BV_SD.tif']);

            resizeFactor = size(disStack.bloodVessels,1)/size(disStack.dFTifStack,1);
            resizeFactor = 1/resizeFactor;

            disStack.bloodVessels = imresize(disStack.bloodVessels, resizeFactor);

            disStack.bloodVessels  = imadjustn(disStack.bloodVessels);


            app.disStack = disStack;


            % set images and slider
            frame = 1;
            setImagesSlider(app, frame);
        end

        function results = setImagesSlider(app, frame)

            selectedButton = app.SelectionTypeButtonGroup.SelectedObject;

            switch selectedButton.Text

                case 'Wave Tracking'

                    drawArrow = @(x,y) quiver(app.imageWaveColAx, x(1),y(1),x(2)-x(1),y(2)-y(1),0 ,'LineWidth',4,'Color',[0.5 0.5 0.5], 'MaxHeadSize',1e2,'AutoScaleFactor',1);

                    imagesc(app.imageDFAx,app.disStack.dFTifStack(:,:,frame));
                    app.imageDFAx.Children.ButtonDownFcn = app.imageDFAx.ButtonDownFcn; % copy button down function to image

                    clear app.imageWaveColAx.Children
                    imagesc(app.imageWaveColAx,squeeze(app.disStack.waveColStack(:,:,frame,:)));
                    hold(app.imageWaveColAx,'on');
                    app.imageWaveColAx.Children.ButtonDownFcn = app.imageWaveColAx.ButtonDownFcn; % copy button down function to image

                    waveTable = app.exStruct.waves.waveTable;
                    wavesDisplayed = unique(waveTable.waveNumber(waveTable.Frame == frame));
                    centersPerFrame = app.exStruct.waves.centerPerFrame;
                    waveStartFrames = app.exStruct.waves.waveFrameFirst;

                    if ~isempty(wavesDisplayed)
                        for x = 1:length(wavesDisplayed)
                            frame2Display = frame- waveStartFrames(wavesDisplayed(x))+1;
                            try
                                xPos = [centersPerFrame{wavesDisplayed(x)}(1,1) centersPerFrame{wavesDisplayed(x)}(frame2Display,1)];
                                yPos = [centersPerFrame{wavesDisplayed(x)}(1,2) centersPerFrame{wavesDisplayed(x)}(frame2Display,2)];

                                drawArrow(xPos,yPos);
                            catch ME
                                if strcmp(ME.identifier, 'Index in position 1 exceeds array bounds.')
                                    msg = [];

                                end
                            end
                        end
                    end

                    hold(app.imageWaveColAx,'off');

                    app.Slider.Value = frame;

                    plotDeleteBB(app);
                    plotSplitBB(app);
                    plotMergeBB(app);

                case 'Vascular Plexus'

                    imagesc(app.imageDFAx,app.disStack.bloodVessels(:,:));

                case 'Retinal Outline'

                    imagesc(app.imageDFAx,app.disStack.dFTifStack(:,:,frame));

                case 'Optic Nerve'

                    imagesc(app.imageDFAx,app.disStack.bloodVessels(:,:));

            end

            plotBloodVesselBoundary(app);
            plotRetinalBoundary(app);
            plotOpticNerveBoundary(app);
        end

        function cellIndx = findClosestObj(app,clickPt)

            frame = app.Slider.Value;
            currentFrameObj = app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.Frame == frame,:);

            currentXY = [currentFrameObj.Centroid];
            [d,indx] = pdist2(currentXY,clickPt,"euclidean","Smallest",1);

            % realign to full table
            frameIDs = find(app.exStruct.waves.waveTable.Frame == frame);

            cellIndx = frameIDs(indx);
        end

        function findDeleteIndxStart(app,cellIndx, clickType)
            waveObjGrand = app.exStruct.waves.waveTable;

            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);

            % get everything in the same wave
            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);
            matchingBB= sum(ismember(waveObjs.BoundingBox(:,:), obOfInterest.BoundingBox),2); % finds sum of matchnig BB box vertices

            [~, matchIndx] = max(matchingBB);

            % for each frame
            overlapFlag = waveObjs.DeleteFlag;


            if overlapFlag(matchIndx) == 1
                % if previously selected,remove object from delete list
                overlapFlag(matchIndx) = 0;
            else
                % find matches across the frames if not selected
                for f = 1:height(waveObjs)
                    if  waveObjs.waveNumber(f) >= obOfInterest.waveNumber
                        overlap = bboxOverlapRatio(obOfInterest.BoundingBox, waveObjs.BoundingBox(f,:));

                        if overlap > 0
                            overlapFlag(f) = 1;
                        end
                    end
                end
            end

            waveObjGrand.DeleteFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = overlapFlag;


            app.exStruct.waves.waveTable = waveObjGrand;

            plotDeleteBB(app);
        end

        function plotDeleteBB(app)

            frame = round(app.Slider.Value); % integer round
            waveObjGrand = app.exStruct.waves.waveTable;

            frameObj = waveObjGrand(waveObjGrand.Frame ==frame,:);

            % find all draw boxes and delete them
            rect = findall(app.imageWaveColAx,'Type', 'Rectangle' ,"EdgeColor", 'r');
            delete(rect);

            rect = findall(app.imageDFAx,'Type', 'Rectangle',"EdgeColor", 'r');
            delete(rect);

            % redraw rectangle BBs
            for ob = 1:height(frameObj)

                if frameObj.DeleteFlag(ob) == 1
                    hold(app.imageWaveColAx,'on');
                    rectangle(app.imageWaveColAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'r')
                    hold(app.imageWaveColAx,'off');

                    hold(app.imageDFAx,'on');
                    rectangle(app.imageDFAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'r')
                    hold(app.imageDFAx,'off');

                end
            end
        end

        function findDeleteIndxEnd(app,cellIndx, clickType)

            waveObjGrand = app.exStruct.waves.waveTable;

            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);
            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);

            frameEnd = obOfInterest.Frame;

            frameRemoveStart = find(waveObjs.Frame == frameEnd+1);
            waveObjs.DeleteFlag(frameRemoveStart:end) = 0;
            waveObjGrand.DeleteFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = waveObjs.DeleteFlag;

            app.exStruct.waves.waveTable = waveObjGrand;

            plotDeleteBB(app)
        end

        function findSplitIndxStart(app,cellIndx)

            waveObjGrand = app.exStruct.waves.waveTable;

            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);
            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);

            % for each frame
            overlapFlag = waveObjs.SplitFlag;


            matchingBB= sum(ismember(waveObjs.BoundingBox(:,:), obOfInterest.BoundingBox),2); % finds sum of matchnig BB box vertices
            [~, matchIndx] = max(matchingBB);

            if overlapFlag(matchIndx) == 1
                % if previously selected,remove object from delete list
                overlapFlag(matchIndx) = 0;
            else

                for f = 1:height(waveObjs)
                    overlap = bboxOverlapRatio(obOfInterest.BoundingBox, waveObjs.BoundingBox(f,:));

                    if overlap > 0
                        overlapFlag(f) = 1;
                    end
                end
            end

            waveObjGrand.SplitFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = overlapFlag;
            app.exStruct.waves.waveTable = waveObjGrand;

            plotSplitBB(app);

        end

        function findSplitIndxEnd(app,cellIndx, clickType)

            waveObjGrand = app.exStruct.waves.waveTable;

            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);
            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);

            frameEnd = obOfInterest.Frame;

            frameRemoveStart = find(waveObjs.Frame == frameEnd+1);
            waveObjs.SplitFlag(frameRemoveStart:end) = 0;
            waveObjGrand.SplitFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = waveObjs.SplitFlag;

            app.exStruct.waves.waveTable = waveObjGrand;

            plotSplitBB(app)
        end

        function plotSplitBB(app)

            frame = round(app.Slider.Value); % integer round
            waveObjGrand = app.exStruct.waves.waveTable;

            % find all draw boxes and delete them
            rect = findall(app.imageWaveColAx,'Type', 'Rectangle', 'EdgeColor','g');
            delete(rect);

            rect = findall(app.imageDFAx,'Type', 'Rectangle', 'EdgeColor','g');
            delete(rect);


            frameObj = waveObjGrand(waveObjGrand.Frame ==frame,:);

            for ob = 1:height(frameObj)

                if frameObj.SplitFlag(ob) == 1
                    hold(app.imageWaveColAx,'on');
                    rectangle(app.imageWaveColAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'g')
                    hold(app.imageWaveColAx,'off');

                    hold(app.imageDFAx,'on');
                    rectangle(app.imageDFAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'g')
                    hold(app.imageDFAx,'off');
                end
            end
        end

        function findMergeIndxStart(app,cellIndx)
            waveObjGrand = app.exStruct.waves.waveTable;


            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);

            % set the wave to merge into
            if isempty(app.merge2Wave)
                app.merge2Wave = obOfInterest.waveNumber;
            end

            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);
            overlapFlag = waveObjs.MergeFlag;


            matchingBB= sum(ismember(waveObjs.BoundingBox(:,:), obOfInterest.BoundingBox),2); % finds sum of matchnig BB box vertices
            [~, matchIndx] = max(matchingBB);

            if overlapFlag(matchIndx) == 1
                % if previously selected,remove object from delete list
                overlapFlag(matchIndx) = 0;
            else

                % for each frame

                for f = 1:height(waveObjs)
                    overlap = bboxOverlapRatio(obOfInterest.BoundingBox, waveObjs.BoundingBox(f,:));

                    if overlap > 0
                        overlapFlag(f) = 1;
                    end
                end
            end

            waveObjGrand.MergeFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = overlapFlag;
            app.exStruct.waves.waveTable = waveObjGrand;

            plotMergeBB(app);
        end

        function findMergeIndxEnd(app,cellIndx)
            waveObjGrand = app.exStruct.waves.waveTable;

            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);
            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);

            frameEnd = obOfInterest.Frame;

            frameRemoveStart = find(waveObjs.Frame == frameEnd+1);
            waveObjs.MergeFlag(frameRemoveStart:end) = 0;
            waveObjGrand.MergeFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = waveObjs.MergeFlag;

            app.exStruct.waves.waveTable = waveObjGrand;

            plotMergeBB(app)
        end

        function plotMergeBB(app)
            frame = round(app.Slider.Value); % integer round
            waveObjGrand = app.exStruct.waves.waveTable;

            frameObj = waveObjGrand(waveObjGrand.Frame ==frame,:);

            % find all draw boxes and delete them
            rect = findall(app.imageWaveColAx,'Type', 'Rectangle', 'EdgeColor','m');
            delete(rect);

            rect = findall(app.imageDFAx,'Type', 'Rectangle', 'EdgeColor','m');
            delete(rect);


            for ob = 1:height(frameObj)

                if frameObj.MergeFlag(ob) == 1
                    hold(app.imageWaveColAx,'on');
                    rectangle(app.imageWaveColAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'm')
                    hold(app.imageWaveColAx,'off');

                    hold(app.imageDFAx,'on');
                    rectangle(app.imageDFAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'm')
                    hold(app.imageDFAx,'off');
                end
            end

        end


        function app = roiUpdater(app,src,evt)

            if strcmp(src.Label,'Plexus')
                app.BV_poly = src;
                app.BV_poly.Position = evt.CurrentPosition;
                app.BV_Position = evt.CurrentPosition;
            end


            if strcmp(src.Label,'Retina')
                app.RetBound_poly = src;
                app.RetBound_poly.Position = evt.CurrentPosition;
                app.RetBound_Position = evt.CurrentPosition;
            end

            if strcmp(src.Label,'Optic')
                app.opticNerve_poly = src;
                app.opticNerve_poly.Position = evt.CurrentPosition;
                app.opticNerve_Position = evt.CurrentPosition;
            end

        end


        function results = plotBloodVesselBoundary(app)

            selectedButton = app.SelectionTypeButtonGroup.SelectedObject;

            switch selectedButton.Text

                % draw polygon shape
                case 'Wave Tracking'
                    if ~isempty(app.BV_Position)
                        try
                            if ~isvalid(app.BV_poly)
                                images.roi.Polygon(app.imageDFAx,'Position', app.BV_Position, 'FaceSelectable', 0, 'Label', 'Plexus', 'FaceAlpha', 0);
                                images.roi.Polygon(app.imageWaveColAx,'Position', app.BV_Position, 'FaceSelectable', 0, 'Color', [0.5 0 0], 'MarkerSize', 0.5, 'FaceAlpha', 0);
                            end
                        catch
                            if isempty(app.BV_poly)
                                app.BV_poly = images.roi.Polygon(app.imageDFAx,'Position', app.BV_Position, 'FaceSelectable', 0, 'Label', 'Plexus', 'FaceAlpha', 0);
                                images.roi.Polygon(app.imageWaveColAx,'Position', app.BV_Position, 'FaceSelectable', 0, 'Color', [0.5 0 0], 'MarkerSize', 0.5, 'FaceAlpha', 0);
                            end

                        end
                    end

                    % draw polygon shape
                case 'Retinal Outline'
                    if ~isempty(app.BV_Position)
                        if ~isvalid(app.BV_poly)
                            app.BV_poly = images.roi.Polygon(app.imageDFAx,'Position', app.BV_Position, 'FaceSelectable', 0, 'Label', 'Plexus', 'FaceAlpha', 0);
                            images.roi.Polygon(app.imageWaveColAx,'Position', app.BV_Position, 'FaceSelectable', 0, 'Color', [0.5 0 0], 'MarkerSize', 0.5, 'FaceAlpha', 0);
                        end
                    end

                    % draw polygon shape
                case 'Optic Nerve'
                    if ~isempty(app.BV_Position)
                        if ~isvalid(app.BV_poly)
                            app.BV_poly = images.roi.Polygon(app.imageDFAx,'Position', app.BV_Position, 'FaceSelectable', 0, 'Label', 'Plexus', 'FaceAlpha', 0);
                            images.roi.Polygon(app.imageWaveColAx,'Position', app.BV_Position, 'FaceSelectable', 0, 'Color', [0.5 0 0], 'MarkerSize', 0.5, 'FaceAlpha', 0);
                        end
                    end

                    % place editable polygon shape
                case 'Vascular Plexus'
                    if ~isempty(app.BV_Position)
                        if ~isvalid(app.BV_poly)
                            app.BV_poly = drawpolygon(app.imageDFAx,'Position', app.BV_Position, 'Label', 'Plexus', 'FaceAlpha', 0);
                            images.roi.Polygon(app.imageWaveColAx,'Position', app.BV_Position, 'FaceSelectable', 0, 'Color', [0.5 0 0], 'MarkerSize', 0.5, 'FaceAlpha', 0);
                            addlistener(app.BV_poly,'ROIMoved',@app.roiUpdater);
                            app.BV_Position = app.BV_poly.Position;
                        end
                    end
            end
        end


        function results = plotRetinalBoundary(app)

            selectedButton = app.SelectionTypeButtonGroup.SelectedObject;

            switch selectedButton.Text

                % draw polygon shape
                case 'Wave Tracking'
                    if ~isempty(app.RetBound_Position)
                        try
                            if ~isvalid(app.RetBound_poly)
                                images.roi.Polygon(app.imageDFAx,'Position', app.RetBound_Position, 'FaceSelectable', 0, 'Label', 'Retina', 'FaceAlpha', 0, 'Color', [0 1 1]);
                                images.roi.Polygon(app.imageWaveColAx,'Position', app.RetBound_Position, 'FaceSelectable', 0, 'MarkerSize', 0.5, 'FaceAlpha', 0, 'Color', [0 1 1]);
                            end
                        catch
                            if isempty(app.RetBound_poly)
                                app.RetBound_poly = images.roi.Polygon(app.imageDFAx,'Position', app.RetBound_Position, 'FaceSelectable', 0, 'Label', 'Retina', 'FaceAlpha', 0, 'Color', [0 1 1]);
                                images.roi.Polygon(app.imageWaveColAx,'Position', app.RetBound_Position, 'FaceSelectable', 0, 'MarkerSize', 0.5, 'FaceAlpha', 0, 'Color', [0 1 1]);
                            end

                        end
                    end

                    % draw polygon shape
                case 'Vascular Plexus'
                    if ~isempty(app.RetBound_Position)
                        if ~isvalid(app.RetBound_poly)
                            app.RetBound_poly = images.roi.Polygon(app.imageDFAx,'Position', app.RetBound_Position, 'FaceSelectable', 0, 'Label', 'Retina', 'FaceAlpha', 0, 'Color', [0 1 1]);
                            images.roi.Polygon(app.imageWaveColAx,'Position', app.RetBound_Position, 'FaceSelectable', 0, 'MarkerSize', 0.5, 'FaceAlpha', 0, 'Color', [0 1 1]);
                        end
                    end

                    % draw polygon shape
                case 'Optic Nerve'
                    if ~isempty(app.RetBound_Position)
                        if ~isvalid(app.RetBound_poly)
                            app.RetBound_poly = images.roi.Polygon(app.imageDFAx,'Position', app.RetBound_Position, 'FaceSelectable', 0, 'Label', 'Retina', 'FaceAlpha', 0, 'Color', [0 1 1]);
                            images.roi.Polygon(app.imageWaveColAx,'Position', app.RetBound_Position, 'FaceSelectable', 0, 'MarkerSize', 0.5, 'FaceAlpha', 0, 'Color', [0 1 1]);
                        end
                    end

                    % place editable polygon shape
                case 'Retinal Outline'
                    if ~isempty(app.RetBound_Position)
                        if ~isvalid(app.RetBound_poly)
                            app.RetBound_poly = drawpolygon(app.imageDFAx,'Position', app.RetBound_Position, 'Label', 'Retina', 'FaceAlpha', 0, 'Color', [0 1 1]);
                            images.roi.Polygon(app.imageWaveColAx,'Position', app.RetBound_Position, 'FaceSelectable', 0, 'MarkerSize', 0.5, 'FaceAlpha', 0, 'Color', [0 1 1]);
                            addlistener(app.RetBound_poly,'ROIMoved',@app.roiUpdater);
                            app.RetBound_Position = app.RetBound_poly.Position;
                        end
                    end
            end
        end


        function results = plotOpticNerveBoundary(app)

            selectedButton = app.SelectionTypeButtonGroup.SelectedObject;

            switch selectedButton.Text

                % draw polygon shape
                case 'Wave Tracking'
                    if ~isempty(app.opticNerve_Position)
                        try
                            if ~isvalid(app.opticNerve_poly)
                                images.roi.Polygon(app.imageDFAx,'Position', app.opticNerve_Position, 'FaceSelectable', 0, 'Label', 'Optic', 'FaceAlpha', 0, 'Color', [1 1 1]);
                                images.roi.Polygon(app.imageWaveColAx,'Position', app.opticNerve_Position, 'FaceSelectable', 0, 'MarkerSize', 0.5, 'FaceAlpha', 0, 'Color', [1 1 1]);
                            end
                        catch
                            if isempty(app.opticNerve_poly)
                                app.opticNerve_poly = images.roi.Polygon(app.imageDFAx,'Position', app.opticNerve_Position, 'FaceSelectable', 0, 'Label', 'Optic', 'FaceAlpha', 0, 'Color', [1 1 1]);
                                images.roi.Polygon(app.imageWaveColAx,'Position', app.opticNerve_Position, 'FaceSelectable', 0, 'MarkerSize', 0.5, 'FaceAlpha', 0, 'Color', [1 1 1]);
                            end

                        end
                    end

                    % draw polygon shape
                case 'Vascular Plexus'
                    if ~isempty(app.opticNerve_Position)
                        if ~isvalid(app.opticNerve_poly)
                            app.opticNerve_poly = images.roi.Polygon(app.imageDFAx,'Position', app.opticNerve_Position, 'FaceSelectable', 0, 'Label', 'Optic', 'FaceAlpha', 0, 'Color', [1 1 1]);
                            images.roi.Polygon(app.imageWaveColAx,'Position', app.opticNerve_Position, 'FaceSelectable', 0, 'MarkerSize', 0.5, 'FaceAlpha', 0, 'Color', [1 1 1]);
                        end
                    end

                    % draw polygon shape
                case 'Retinal Outline'
                    if ~isempty(app.opticNerve_Position)
                        if ~isvalid(app.opticNerve_poly)
                            app.opticNerve_poly = images.roi.Polygon(app.imageDFAx,'Position', app.opticNerve_Position, 'FaceSelectable', 0, 'Label', 'Optic', 'FaceAlpha', 0, 'Color', [1 1 1]);
                            images.roi.Polygon(app.imageWaveColAx,'Position', app.opticNerve_Position, 'FaceSelectable', 0, 'MarkerSize', 0.5, 'FaceAlpha', 0, 'Color', [1 1 1]);
                        end
                    end

                    % place editable polygon shape
                case 'Optic Nerve'
                    if ~isempty(app.opticNerve_Position)
                        if ~isvalid(app.opticNerve_poly)
                            app.opticNerve_poly = drawpolygon(app.imageDFAx,'Position', app.opticNerve_Position, 'Label', 'Optic', 'FaceAlpha', 0, 'Color', [1 1 1]);
                            images.roi.Polygon(app.imageWaveColAx,'Position', app.opticNerve_Position, 'FaceSelectable', 0, 'MarkerSize', 0.5, 'FaceAlpha', 0, 'Color', [1 1 1]);
                            addlistener(app.opticNerve_poly,'ROIMoved',@app.roiUpdater);
                            app.opticNerve_Position = app.opticNerve_poly.Position;
                        end
                    end
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % center figure on startup
            movegui(app.UIFigure, 'center');
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            % Display uigetfile dialog

            if ~isempty(app.homeDir)
                filterspec = {[app.homeDir '*.mat']};
                [f, p] = uigetfile(filterspec);
            else
                filterspec = {'*.mat'};
                [f, p] = uigetfile(filterspec);

            end

            app.homeDir = p;

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                load([p f]);
                exStruct.filePath = fullfile(p,[f(1:end-13) '.nd2']);
                app.exStruct = exStruct;

                % load in DeleteFlag
                if ~any(ismember(app.exStruct.waves.waveTable.Properties.VariableNames,'DeleteFlag'))
                    app.exStruct.waves.waveTable.DeleteFlag = zeros(height(app.exStruct.waves.waveTable),1);
                end

                if ~any(ismember(app.exStruct.waves.waveTable.Properties.VariableNames,'SplitFlag'))
                    app.exStruct.waves.waveTable.SplitFlag = zeros(height(app.exStruct.waves.waveTable),1);
                end

                if ~any(ismember(app.exStruct.waves.waveTable.Properties.VariableNames,'MergeFlag'))
                    app.exStruct.waves.waveTable.MergeFlag = zeros(height(app.exStruct.waves.waveTable),1);
                end

                loadImages(app);

                % intial slider setup
                app.Slider.Limits = [1 size(exStruct.dF,3)];
                app.Slider.Value = 1;
                app.SliderLabel.Text = f(1:end-13);

                % inital wave object for merge
                app.merge2Wave =[];

                % bring to front
                figure(app.UIFigure);

                % remove vascular plexus ROIs
                app.BV_Position =[];
                child_handles = findall(app.UIFigure, 'Label','Plexus');
                delete(child_handles);
                child_handles = findall(app.UIFigure, 'Label','');
                delete(child_handles);

                % remove retina ROIs
                app.RetBound_Position =[];
                child_handles = findall(app.UIFigure, 'Label','Retina');
                delete(child_handles);
                child_handles = findall(app.UIFigure, 'Label','');
                delete(child_handles);

                % remove optic nerve ROIs
                app.opticNerve_Position =[];
                child_handles = findall(app.UIFigure, 'Label','Optic');
                delete(child_handles);
                child_handles = findall(app.UIFigure, 'Label','');
                delete(child_handles);

                % check if blood vessels have been previously calculated
                if isfield(app.exStruct.waves, 'BV_Position')
                    app.BV_Position = app.exStruct.waves.BV_Position;
                    plotBloodVesselBoundary(app);
                end


                if isfield(app.exStruct.waves, 'retinaBoundMask')
                    tempVerts = bwboundaries(app.exStruct.waves.retinaBoundMask');
                    tempVerts = tempVerts{1};

                    if length(tempVerts)>50 % if too long then resample to make the plotting faster
                        lenVerts = length(tempVerts);
                        target = 50;

                        sampIndexes = round(interp1( 1:lenVerts, linspace(1, lenVerts, target)));

                    end
                    app.RetBound_Position = tempVerts(sampIndexes,:);
                    plotRetinalBoundary(app);
                end

                if isfield(app.exStruct.waves, 'opticNerveMask')
                    tempVerts = bwboundaries(app.exStruct.waves.opticNerveMask');
                    tempVerts = tempVerts{1};

                    if length(tempVerts)>50 % if too long then resample to make the plotting faster
                        lenVerts = length(tempVerts);
                        target = 50;

                        sampIndexes = round(interp1( 1:lenVerts, linspace(1, lenVerts, target)));

                        app.opticNerve_Position = tempVerts(sampIndexes,:);
                    else
                        app.opticNerve_Position = tempVerts;
                    end


                    plotOpticNerveBoundary(app);
                end
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            frame = round(app.Slider.Value); % integer round

            app.Slider.Value = frame; % set

            setImagesSlider(app, frame);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key

                case 'rightarrow' % increase frame number

                    frame = round(app.Slider.Value); % integer round
                    newVal = frame +1; % set

                    if newVal >app.Slider.Limits(2)
                        newVal = app.Slider.Limits(2);
                    end

                    setImagesSlider(app, newVal);

                case 'leftarrow' % decrease frame number

                    frame = round(app.Slider.Value); % integer round
                    newVal = frame -1; % set

                    if newVal < app.Slider.Limits(1)
                        newVal = app.Slider.Limits(1);
                    end

                    setImagesSlider(app, newVal)

                case 'space' % clear selection
                    app.exStruct.waves.waveTable.DeleteFlag = zeros(size(app.exStruct.waves.waveTable.DeleteFlag));
                    app.exStruct.waves.waveTable.SplitFlag = zeros(size(app.exStruct.waves.waveTable.SplitFlag));
                    app.exStruct.waves.waveTable.MergeFlag = zeros(size(app.exStruct.waves.waveTable.MergeFlag));

                    app.merge2Wave = [];

                    plotDeleteBB(app);
                    plotSplitBB(app);
                    plotMergeBB(app);

            end

        end

        % Window scroll wheel function: UIFigure
        function ScrollWheelCallbck(app, event)
            %             verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;

            frame = round(app.Slider.Value); % integer round
            newVal = frame  + verticalScrollCount; % set

            if newVal < app.Slider.Limits(1)
                newVal = app.Slider.Limits(1);
            elseif newVal >app.Slider.Limits(2)
                newVal = app.Slider.Limits(2);
            end

            setImagesSlider(app, newVal)

        end

        % Button down function: imageWaveColAx
        function imageWaveColAxButtonDown(app, event)
            clickPt = round(event.IntersectionPoint(1:2));

            % match wave object
            cellIndx = findClosestObj(app,clickPt);

            cntrlDw =  getAsyncKeyState(VirtualKeyCode.VK_CONTROL);
            atlDw = getAsyncKeyState(VirtualKeyCode.VK_MENU);

            if cntrlDw == 0 && atlDw == 0

                % delete ROIs
                switch event.Button

                    case 1
                        findDeleteIndxStart(app,cellIndx, event.Button);

                    case 2
                        deleteFlag = app.exStruct.waves.waveTable.DeleteFlag(cellIndx);

                        if deleteFlag == 1
                            app.exStruct.waves.waveTable.DeleteFlag(cellIndx) = 0;
                        else
                            app.exStruct.waves.waveTable.DeleteFlag(cellIndx) = 1;
                        end

                        plotDeleteBB(app);

                    case 3
                        findDeleteIndxEnd(app,cellIndx, event.Button);
                end

                % split ROI off into new wave
            elseif cntrlDw == 1 && atlDw == 0
                switch event.Button

                    case 1
                        findSplitIndxStart(app,cellIndx);

                    case 2
                        splitFlag = app.exStruct.waves.waveTable.SplitFlag(cellIndx);

                        if splitFlag == 1
                            app.exStruct.waves.waveTable.SplitFlag(cellIndx) = 0;
                        else
                            app.exStruct.waves.waveTable.SplitFlag(cellIndx) = 1;
                        end

                        plotSplitBB(app);

                    case 3
                        findSplitIndxEnd(app,cellIndx);

                end

                % merge ROI
            elseif cntrlDw == 0 && atlDw == 1
                switch event.Button

                    case 1
                        findMergeIndxStart(app,cellIndx);

                    case 2
                        mergeFlag = app.exStruct.waves.waveTable.MergeFlag(cellIndx);

                        if mergeFlag == 1
                            app.exStruct.waves.waveTable.MergeFlag(cellIndx) = 0;
                        else
                            app.exStruct.waves.waveTable.MergeFlag(cellIndx) = 1;
                        end

                        plotMergeBB(app);

                    case 3
                        findMergeIndxEnd(app,cellIndx);

                end
            end

            clear atlDw cntrlDw
        end

        % Button down function: imageDFAx
        function imageDFAxButtonDown(app, event)
            clickPt = round(event.IntersectionPoint(1:2));

            % match wave object
            cellIndx = findClosestObj(app,clickPt);

            cntrlDw =  getAsyncKeyState(VirtualKeyCode.VK_CONTROL);
            atlDw = getAsyncKeyState(VirtualKeyCode.VK_MENU);

            if cntrlDw == 0 && atlDw == 0

                switch event.Button

                    case 1
                        findDeleteIndxStart(app,cellIndx, event.Button);

                    case 3
                        findDeleteIndxEnd(app,cellIndx, event.Button);
                end
            elseif cntrlDw == 1 && atlDw == 0
                switch event.Button

                    case 1
                        findSplitIndxStart(app,cellIndx);

                    case 3
                        findSplitIndxEnd(app,cellIndx);

                end

            elseif cntrlDw == 0 && atlDw == 1
                switch event.Button

                    case 1
                        findMergeIndxStart(app,cellIndx);

                    case 3
                        findMergeIndxEnd(app,cellIndx);

                end
            end
        end

        % Button pushed function: RecatWaves
        function RecatWavesPushed(app, event)

            % remove deletes
            app.exStruct.waves.waveTable = app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.DeleteFlag == 0,:);

            %% save blood vessel plexus ROI
            % blood vessel area flag
            if ~isempty(app.BV_Position)
                inPlexusFlag = inpolygon(app.exStruct.waves.waveTable.Centroid(:,1),app.exStruct.waves.waveTable.Centroid(:,1),app.BV_Position(:,1),app.BV_Position(:,2));
            else
                inPlexusFlag = zeros(height(app.exStruct.waves.waveTable),1);
            end

            app.exStruct.waves.waveTable.inPlexusFlag = inPlexusFlag;

            app.exStruct.waves.BV_poly = app.BV_Position;

            %% get waves affected by split

            maxWaveNo = max(app.exStruct.waves.waveTable.waveNumber);
            splitObjs = app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.SplitFlag == 1,:);
            waveAffected = unique(splitObjs.waveNumber);

            % renumber wave splits
            count = 1;
            for w = 1:length(waveAffected)

                wav = waveAffected(w);
                % if wave split flag == 1 and wave number is correct
                app.exStruct.waves.waveTable.waveNumber(app.exStruct.waves.waveTable.SplitFlag == 1 & app.exStruct.waves.waveTable.waveNumber == wav) = maxWaveNo + count;
                count = count +1;
            end

            app.exStruct.waves.waveTable.SplitFlag = zeros(height( app.exStruct.waves.waveTable),1);

            %% deal with merges
            mergeWaveObj = app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.MergeFlag == 1,:);
            minWaveNo = app.merge2Wave;
            %             minWaveNo = min(mergeWaveObj.waveNumber);

            if ~isempty(minWaveNo)

                mergeWaveObj.waveNumber = minWaveNo * ones(height(mergeWaveObj),1);
                mergeWaveObj.MergeFlag = zeros(height(mergeWaveObj),1);

                app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.MergeFlag == 1,:) = mergeWaveObj;

            end

            app.merge2Wave = [];


            %% remove waves that don't move that far if filter is selected
            if app.DistMovedFiltButton.Value == 1
                % for each wave
                waveNums = unique(app.exStruct.waves.waveTable.waveNumber);
                wavePassTest = zeros(length(waveNums),1);
                for w = 1:length(waveNums)
                    % create wave object image
                    objectIm = zeros(512, 512);
                    subTable = app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.waveNumber == waveNums(w),:);
                    [firstObjX,  firstObjY]= ind2sub([512 512],gather(subTable.PixelIdxList{1}));

                    for pix = 1:length(firstObjX)
                        objectIm(firstObjX(pix), firstObjY(pix)) = 1;
                    end

                    % get the larger diameter
                    imProps = regionprops(objectIm,  'MinorAxisLength');

                    % absoulte distance travelled
                    abDisW = pdist2(subTable.Centroid(1,:), subTable.Centroid(end,:));

                    if imProps.MinorAxisLength < abDisW
                        wavePassTest(w) = true;
                    end
                end

                for w = 1:length(waveNums)
                    if wavePassTest(w) == 0
                        app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.waveNumber == waveNums(w),:) =[];
                    end
                end
            end

            %% renumber waves
            [~,~,newWaveNo] = unique(app.exStruct.waves.waveTable.waveNumber);
            app.exStruct.waves.waveTable.waveNumber = newWaveNo;

            [app.exStruct.waves, newWaveColStack] = recalculateWaveMetrics(app);

            app.disStack.waveColStack = newWaveColStack;

            frame = round(app.Slider.Value); % integer round
            setImagesSlider(app, frame);

        end

        % Selection changed function: SelectionTypeButtonGroup
        function SelectionTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectionTypeButtonGroup.SelectedObject;

            switch selectedButton.Text

                case 'Wave Tracking'
                    setImagesSlider(app, app.Slider.Value);

                case 'Vascular Plexus'
                    setImagesSlider(app, app.Slider.Value);

                    if isempty(app.BV_poly)
                        app.BV_poly = drawpolygon(app.imageDFAx, 'Label', 'Plexus', 'FaceAlpha', 0);
                        addlistener(app.BV_poly,'ROIMoved',@app.roiUpdater);
                        app.BV_Position = app.BV_poly.Position;
                    elseif ~isvalid(app.BV_poly)
                        app.BV_poly = drawpolygon(app.imageDFAx, 'Label', 'Plexus','FaceAlpha', 0);
                        addlistener(app.BV_poly,'ROIMoved',@app.roiUpdater);
                        app.BV_Position = app.BV_poly.Position;
                    end

                case 'Retinal Outline'
                    setImagesSlider(app, app.Slider.Value);

                    if isempty(app.RetBound_poly)
                        app.RetBound_poly = drawpolygon(app.imageDFAx, 'Label', 'Retina', 'FaceAlpha', 0, 'Color', [0 1 1]);
                        addlistener(app.RetBound_poly,'ROIMoved',@app.roiUpdater);
                        app.RetBound_Position = app.RetBound_poly.Position;
                    elseif ~isvalid(app.RetBound_poly)
                        app.RetBound_poly = drawpolygon(app.imageDFAx, 'Label', 'Retina', 'FaceAlpha', 0, 'Color', [0 1 1]);
                        addlistener(app.RetBound_poly,'ROIMoved',@app.roiUpdater);
                        app.RetBound_Position = app.RetBound_poly.Position;
                    end

                case 'Optic Nerve'
                    setImagesSlider(app, app.Slider.Value);

                    if isempty(app.opticNerve_poly)
                        app.opticNerve_poly = drawpolygon(app.imageDFAx, 'Label', 'Optic', 'FaceAlpha', 0, 'Color', [1 1 1]);
                        addlistener(app.opticNerve_poly,'ROIMoved',@app.roiUpdater);
                        app.opticNerve_Position = app.opticNerve_poly.Position;
                    elseif ~isvalid(app.opticNerve_poly)
                        app.opticNerve_poly = drawpolygon(app.imageDFAx, 'Label', 'Optic', 'FaceAlpha', 0, 'Color', [1 1 1]);
                        addlistener(app.opticNerve_poly,'ROIMoved',@app.roiUpdater);
                        app.opticNerve_Position = app.opticNerve_poly.Position;
                    end
            end

        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)

            %% save the struct and wave color image stack
            % save exStruct.mat

            exStruct = app.exStruct;

            % make sure BV poly position is transferred
            exStruct.waves.BV_Position = app.BV_Position;

            %% save retina bounds image

            if ~isempty(app.RetBound_Position)
                exStruct.waves.retinaBoundMask = poly2mask(app.RetBound_Position(:,1),app.RetBound_Position(:,2),size(exStruct.dF,1),size(exStruct.dF,1));
            end

            %% save optic nerve bounds image
            if ~isempty(app.opticNerve_Position)
                exStruct.waves.opticNerveMask = poly2mask(app.opticNerve_Position(:,1),app.opticNerve_Position(:,2),size(exStruct.dF,1),size(exStruct.dF,1));
            end

            save([app.exStruct.filePath(1:end-4) '_ExStruct.mat'], 'exStruct', '-v7.3');

            % save RGB timeseries stack
            options.color = 1;
            colStack = im2uint8(app.disStack.waveColStack);
            saveastiff(permute(colStack, [1 2 4 3]), fullfile([exStruct.filePath(1:end-4) '_waveCol.tif']), options);
            %% create wave extent images

            waveCols = distinguishable_colors(length(unique(app.exStruct.waves.waveTable.waveNumber)),{'w','k'});


            % get image to color over
            SDImage = std(double(app.disStack.dFTifStack),[],3);
            SDImage = imadjust(rescale(SDImage));

            % color over each wave extent
            waves.waveArea = [];
            waves.waveAreaMicron = [];

            for w = 1:max(app.exStruct.waves.waveTable.waveNumber)

                % get all objects for a single waves
                subTabIndx = app.exStruct.waves.waveTable.waveNumber == w;
                subTable = app.exStruct.waves.waveTable(subTabIndx,:);

                % get all the pixels involved in wave
                wavePixels = unique(cat(1,subTable.PixelIdxList{:}));
                [wavePixX, wavePixY] = ind2sub(size(SDImage), wavePixels);

                % get wave extent in pixels
                waves.waveArea(w) = length(wavePixels);
                waves.waveAreaMicron(w) = length(wavePixels) * (exStruct.downsampledRes * exStruct.downsampledRes);


                SDImageRGB = repmat(SDImage, 1, 1, 3);

                % colorize each pixel
                for pix = 1:length(wavePixX)
                    SDImageRGB(wavePixX(pix), wavePixY(pix) ,:) = squeeze(SDImageRGB( wavePixX(pix), wavePixY(pix),:))' .* waveCols(w,:);
                end

                SDImageRGB = insertMarker(SDImageRGB,exStruct.waves.centerPerFrame{w}(1,:),"circle");

                % save the things
                wavePicDir = [exStruct.filePath(1:end-4) '_waveMaps'];

                if ~exist(wavePicDir)
                    mkdir(wavePicDir);
                else
                    if w == 1
                        rmdir(wavePicDir, 's');
                        mkdir(wavePicDir);
                    end
                end

                [~, fileName] = fileparts(exStruct.filePath);

                imwrite(SDImageRGB, fullfile(wavePicDir, sprintf('%s_wave_%03d.tif', fileName, w)));
            end

            app.LastSavedBox.Value = ['Last saved: '  string(datetime('now', 'Format', 'HH:mm:ss dd-MM-yyyy'))];
        end

        % Button pushed function: waveFilter
        function waveFilterButtonPushed(app, event)

            % recalc wave tracking
            dF_filepath = [app.exStruct.filePath(1:end-4) '_dF_F.tif'];
            ndFilepath = app.exStruct.filePath;

            trackCalciumWavesGPU(dF_filepath, 1);
            disp('Finished recalculating waves');

            % Make sure user didn't cancel uigetfile dialog
            load([ndFilepath(1:end-4) '_ExStruct.mat']);
            exStruct.filePath = ndFilepath;
            app.exStruct = exStruct;

            % load in DeleteFlag
            if ~any(ismember(app.exStruct.waves.waveTable.Properties.VariableNames,'DeleteFlag'))
                app.exStruct.waves.waveTable.DeleteFlag = zeros(height(app.exStruct.waves.waveTable),1);
            end

            if ~any(ismember(app.exStruct.waves.waveTable.Properties.VariableNames,'SplitFlag'))
                app.exStruct.waves.waveTable.SplitFlag = zeros(height(app.exStruct.waves.waveTable),1);
            end

            if ~any(ismember(app.exStruct.waves.waveTable.Properties.VariableNames,'MergeFlag'))
                app.exStruct.waves.waveTable.MergeFlag = zeros(height(app.exStruct.waves.waveTable),1);
            end

            loadImages(app);

            % intial slider setup
            app.Slider.Limits = [1 length(exStruct.xyShifts)];
            app.Slider.Value = 1;
            app.SliderLabel.Text = ndFilepath(1:end-4);

            % inital wave object for merge
            app.merge2Wave =[];

            % bring to front
            figure(app.UIFigure);

            % remove vascular plexus ROIs
            app.BV_Position =[];
            child_handles = findall(app.UIFigure, 'Label','Plexus');
            delete(child_handles);
            child_handles = findall(app.UIFigure, 'Label','');
            delete(child_handles);

            % remove Retina ROIs
            app.BV_Position =[];
            child_handles = findall(app.UIFigure, 'Retina','Plexus');
            delete(child_handles);
            child_handles = findall(app.UIFigure, 'Retina','');
            delete(child_handles);

            % remove optic nerve ROIs
            app.BV_Position =[];
            child_handles = findall(app.UIFigure, 'Optic','Plexus');
            delete(child_handles);
            child_handles = findall(app.UIFigure, 'Optic','');
            delete(child_handles);


        end

        % Value changed function: PlayMovieButton
        function PlayMovieButtonValueChanged(app, event)
            value = app.PlayMovieButton.Value;

            while value == 1

                frame = round(app.Slider.Value); % integer round
                if frame > app.Slider.Limits(2)
                    frame = 0;
                end

                pause(1/app.FPS.Value);
                frame = frame +1;

                value = app.PlayMovieButton.Value;

                app.Slider.Value = frame; % set
                setImagesSlider(app, frame);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @ScrollWheelCallbck, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.imageDFAx = uiaxes(app.UIFigure);
            app.imageDFAx.XLim = [0 512];
            app.imageDFAx.YLim = [0 512];
            app.imageDFAx.XTick = [];
            app.imageDFAx.YTick = [];
            app.imageDFAx.ZTick = [];
            app.imageDFAx.ButtonDownFcn = createCallbackFcn(app, @imageDFAxButtonDown, true);
            colormap(app.imageDFAx, 'gray')
            app.imageWaveColAx = uiaxes(app.UIFigure);
            app.imageWaveColAx.XLim = [0 512];
            app.imageWaveColAx.YLim = [0 512];
            app.imageWaveColAx.XTick = [];
            app.imageWaveColAx.YTick = [];
            app.imageWaveColAx.ZTick = [];
            app.imageWaveColAx.ButtonDownFcn = createCallbackFcn(app, @imageWaveColAxButtonDown, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load in Experiment Structure';
            app.RecatWaves = uibutton(app.UIFigure, 'push');
            app.RecatWaves.ButtonPushedFcn = createCallbackFcn(app, @RecatWavesPushed, true);
            app.RecatWaves.Text = 'Recat waves';
            app.SelectionTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectionTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectionTypeButtonGroupSelectionChanged, true);
            app.SelectionTypeButtonGroup.Title = 'Selection Type';
            app.WaveTrackingButton = uiradiobutton(app.SelectionTypeButtonGroup);
            app.WaveTrackingButton.Text = 'Wave Tracking';
            app.WaveTrackingButton.Value = true;
            app.VascularPlexusButton = uiradiobutton(app.SelectionTypeButtonGroup);
            app.VascularPlexusButton.Text = 'Vascular Plexus';
            app.RetinalOutlineButton = uiradiobutton(app.SelectionTypeButtonGroup);
            app.RetinalOutlineButton.Text = 'Retinal Outline';
            app.OpticNerveButton = uiradiobutton(app.SelectionTypeButtonGroup);
            app.OpticNerveButton.Text = 'Optic Nerve';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'Save';
            app.waveFilter = uibutton(app.UIFigure, 'push');
            app.waveFilter.ButtonPushedFcn = createCallbackFcn(app, @waveFilterButtonPushed, true);
            app.waveFilter.Text = 'Wave Filter';
            app.DistMovedFiltButton = uibutton(app.UIFigure, 'state');
            app.DistMovedFiltButton.Text = 'Dist Moved Filt';
            app.LastSavedBox = uitextarea(app.UIFigure);
            app.PlayMovieButton = uibutton(app.UIFigure, 'state');
            app.PlayMovieButton.ValueChangedFcn = createCallbackFcn(app, @PlayMovieButtonValueChanged, true);
            app.PlayMovieButton.Text = 'Play Movie';
            app.FPSEditFieldLabel = uilabel(app.UIFigure);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPS = uieditfield(app.UIFigure, 'numeric');
            app.FPS.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waveTrackingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end