classdef waveTrackingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        saveRecalcButton  matlab.ui.control.Button
        LoadFileButton    matlab.ui.control.Button
        Slider            matlab.ui.control.Slider
        SliderLabel       matlab.ui.control.Label
        imageWaveColAx    matlab.ui.control.UIAxes
        imageDFAx         matlab.ui.control.UIAxes
    end


    properties (Access = private)

    end

    properties (Access = public)
        disStack % Description
        exStruct
        mergeStruct
        homeDir
    end

    methods (Access = private)

        function app = loadImages(app)

            exStruct = app.exStruct;

            % get filepath strings
            dF_filepath = [exStruct.filePath(1:end-4) '_dF_F.tif'];
            waveColFilepath = [exStruct.filePath(1:end-4) '_waveCol.tif'];

            % load in dF images and waveCol Images
            disStack.dFTifStack = read_Tiffs(dF_filepath);
            disStack.dFTifStack = imadjustn(disStack.dFTifStack);
            disStack.waveColStack = tiffreadVolume(waveColFilepath);

            app.disStack = disStack;


            % set images and slider
            frame = 1;
            setImagesSlider(app, frame);
        end

        function results = setImagesSlider(app, frame)

            drawArrow = @(x,y) quiver(app.imageWaveColAx, x(1),y(1),x(2)-x(1),y(2)-y(1),0 ,'LineWidth',4,'Color',[0.5 0.5 0.5], 'MaxHeadSize',1e2,'AutoScaleFactor',1);

            imagesc(app.imageDFAx,app.disStack.dFTifStack(:,:,frame));
            app.imageDFAx.Children.ButtonDownFcn = app.imageDFAx.ButtonDownFcn; % copy button down function to image

            imagesc(app.imageWaveColAx,squeeze(app.disStack.waveColStack(:,:,frame,:)));
            hold(app.imageWaveColAx,'on');
            app.imageWaveColAx.Children.ButtonDownFcn = app.imageWaveColAx.ButtonDownFcn; % copy button down function to image

            waveTable = app.exStruct.waves.waveTable;
            wavesDisplayed = unique(waveTable.waveNumber(waveTable.Frame == frame));
            centersPerFrame = app.exStruct.waves.centerPerFrame;
            waveStartFrames = app.exStruct.waves.waveFrameFirst;

            if ~isempty(wavesDisplayed)
                for x = 1:length(wavesDisplayed)
                    frame2Display = frame- waveStartFrames(wavesDisplayed(x))+1;

                    xPos = [centersPerFrame{wavesDisplayed(x)}(1,1) centersPerFrame{wavesDisplayed(x)}(frame2Display,1)];
                    yPos = [centersPerFrame{wavesDisplayed(x)}(1,2) centersPerFrame{wavesDisplayed(x)}(frame2Display,2)];

                    drawArrow(xPos,yPos);
                end
            end

            hold(app.imageWaveColAx,'off');

            app.Slider.Value = frame;

            plotDeleteBB(app);
            plotSplitBB(app);
            plotMergeBB(app);

        end

        function cellIndx = findClosestObj(app,clickPt)

            frame = app.Slider.Value;
            currentFrameObj = app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.Frame == frame,:);

            currentXY = [currentFrameObj.Centroid];
            [d,indx] = pdist2(currentXY,clickPt,"euclidean","Smallest",1);

            % realign to full table
            frameIDs = find(app.exStruct.waves.waveTable.Frame == frame);

            cellIndx = frameIDs(indx);
        end

        function findDeleteIndxStart(app,cellIndx, clickType)
            waveObjGrand = app.exStruct.waves.waveTable;

            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);

            % get everything in the same wave
            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);
            matchingBB= sum(ismember(waveObjs.BoundingBox(:,:), obOfInterest.BoundingBox),2); % finds sum of matchnig BB box vertices

            [~, matchIndx] = max(matchingBB);

            % for each frame
            overlapFlag = waveObjs.DeleteFlag;


            if overlapFlag(matchIndx) == 1
                % if previously selected,remove object from delete list
                overlapFlag(matchIndx) = 0;
            else
                % find matches across the frames if not selected
                for f = 1:height(waveObjs)
                    if  waveObjs.waveNumber(f) >= obOfInterest.waveNumber
                        overlap = bboxOverlapRatio(obOfInterest.BoundingBox, waveObjs.BoundingBox(f,:));

                        if overlap > 0
                            overlapFlag(f) = 1;
                        end
                    end
                end
            end

            waveObjGrand.DeleteFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = overlapFlag;


            app.exStruct.waves.waveTable = waveObjGrand;

            plotDeleteBB(app);
        end

        function plotDeleteBB(app)

            frame = round(app.Slider.Value); % integer round
            waveObjGrand = app.exStruct.waves.waveTable;

            frameObj = waveObjGrand(waveObjGrand.Frame ==frame,:);

            % find all draw boxes and delete them
            rect = findall(app.imageWaveColAx,'Type', 'Rectangle' ,"EdgeColor", 'r');
            delete(rect);

            rect = findall(app.imageDFAx,'Type', 'Rectangle',"EdgeColor", 'r');
            delete(rect);

            % redraw rectangle BBs
            for ob = 1:height(frameObj)

                if frameObj.DeleteFlag(ob) == 1
                    hold(app.imageWaveColAx,'on');
                    rectangle(app.imageWaveColAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'r')
                    hold(app.imageWaveColAx,'off');

                    hold(app.imageDFAx,'on');
                    rectangle(app.imageDFAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'r')
                    hold(app.imageDFAx,'off');

                end
            end
        end

        function findDeleteIndxEnd(app,cellIndx, clickType)

            waveObjGrand = app.exStruct.waves.waveTable;

            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);
            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);

            frameEnd = obOfInterest.Frame;

            frameRemoveStart = find(waveObjs.Frame == frameEnd+1);
            waveObjs.DeleteFlag(frameRemoveStart:end) = 0;
            waveObjGrand.DeleteFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = waveObjs.DeleteFlag;

            app.exStruct.waves.waveTable = waveObjGrand;

            plotDeleteBB(app)
        end

        function findSplitIndxStart(app,cellIndx)

            waveObjGrand = app.exStruct.waves.waveTable;

            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);
            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);

            % for each frame
            overlapFlag = waveObjs.SplitFlag;


            matchingBB= sum(ismember(waveObjs.BoundingBox(:,:), obOfInterest.BoundingBox),2); % finds sum of matchnig BB box vertices
            [~, matchIndx] = max(matchingBB);

            if overlapFlag(matchIndx) == 1
                % if previously selected,remove object from delete list
                overlapFlag(matchIndx) = 0;
            else

                for f = 1:height(waveObjs)
                    overlap = bboxOverlapRatio(obOfInterest.BoundingBox, waveObjs.BoundingBox(f,:));

                    if overlap > 0
                        overlapFlag(f) = 1;
                    end
                end
            end

            waveObjGrand.SplitFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = overlapFlag;
            app.exStruct.waves.waveTable = waveObjGrand;

            plotSplitBB(app);

        end

        function findSplitIndxEnd(app,cellIndx, clickType)

            waveObjGrand = app.exStruct.waves.waveTable;

            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);
            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);

            frameEnd = obOfInterest.Frame;

            frameRemoveStart = find(waveObjs.Frame == frameEnd+1);
            waveObjs.SplitFlag(frameRemoveStart:end) = 0;
            waveObjGrand.SplitFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = waveObjs.SplitFlag;

            app.exStruct.waves.waveTable = waveObjGrand;

            plotSplitBB(app)
        end

        function plotSplitBB(app)

            frame = round(app.Slider.Value); % integer round
            waveObjGrand = app.exStruct.waves.waveTable;

            frameObj = waveObjGrand(waveObjGrand.Frame ==frame,:);

            for ob = 1:height(frameObj)

                if frameObj.SplitFlag(ob) == 1
                    hold(app.imageWaveColAx,'on');
                    rectangle(app.imageWaveColAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'g')
                    hold(app.imageWaveColAx,'off');

                    hold(app.imageDFAx,'on');
                    rectangle(app.imageDFAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'g')
                    hold(app.imageDFAx,'off');
                end
            end
        end

        function findMergeIndxStart(app,cellIndx)
            waveObjGrand = app.exStruct.waves.waveTable;


            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);
            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);
            overlapFlag = waveObjs.MergeFlag;


            matchingBB= sum(ismember(waveObjs.BoundingBox(:,:), obOfInterest.BoundingBox),2); % finds sum of matchnig BB box vertices
            [~, matchIndx] = max(matchingBB);

            if overlapFlag(matchIndx) == 1
                % if previously selected,remove object from delete list
                overlapFlag(matchIndx) = 0;
            else

                % for each frame

                for f = 1:height(waveObjs)
                    overlap = bboxOverlapRatio(obOfInterest.BoundingBox, waveObjs.BoundingBox(f,:));

                    if overlap > 0
                        overlapFlag(f) = 1;
                    end
                end
            end

            waveObjGrand.MergeFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = overlapFlag;
            app.exStruct.waves.waveTable = waveObjGrand;

            plotMergeBB(app);
        end

        function findMergeIndxEnd(app,cellIndx)
            waveObjGrand = app.exStruct.waves.waveTable;

            % isolate wave objects
            obOfInterest = waveObjGrand(cellIndx,:);
            waveObjs = waveObjGrand(waveObjGrand.waveNumber ==obOfInterest.waveNumber,:);

            frameEnd = obOfInterest.Frame;

            frameRemoveStart = find(waveObjs.Frame == frameEnd+1);
            waveObjs.MergeFlag(frameRemoveStart:end) = 0;
            waveObjGrand.MergeFlag(waveObjGrand.waveNumber ==obOfInterest.waveNumber) = waveObjs.MergeFlag;

            app.exStruct.waves.waveTable = waveObjGrand;

            plotMergeBB(app)
        end

        function plotMergeBB(app)
            frame = round(app.Slider.Value); % integer round
            waveObjGrand = app.exStruct.waves.waveTable;

            frameObj = waveObjGrand(waveObjGrand.Frame ==frame,:);

            % find all draw boxes and delete them
            rect = findall(app.imageWaveColAx,'Type', 'Rectangle', 'EdgeColor','m');
            delete(rect);

            rect = findall(app.imageDFAx,'Type', 'Rectangle', 'EdgeColor','m');
            delete(rect);


            for ob = 1:height(frameObj)

                if frameObj.MergeFlag(ob) == 1
                    hold(app.imageWaveColAx,'on');
                    rectangle(app.imageWaveColAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'm')
                    hold(app.imageWaveColAx,'off');

                    hold(app.imageDFAx,'on');
                    rectangle(app.imageDFAx, "Position",frameObj.BoundingBox(ob,:),"EdgeColor", 'm')
                    hold(app.imageDFAx,'off');
                end
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % center figure on startup
            movegui(app.UIFigure, 'center');
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            % Display uigetfile dialog

            if ~isempty(app.homeDir)
                filterspec = {[app.homeDir '*.mat']};
                [f, p] = uigetfile(filterspec);
            else
                filterspec = {'*.mat'};
                [f, p] = uigetfile(filterspec);

            end

            app.homeDir = p;

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                load([p f]);
                exStruct.filePath = fullfile(p,[f(1:end-13) '.nd2']);
                app.exStruct = exStruct;

                % load in DeleteFlag
                if ~any(ismember(app.exStruct.waves.waveTable.Properties.VariableNames,'DeleteFlag'))
                    app.exStruct.waves.waveTable.DeleteFlag = zeros(height(app.exStruct.waves.waveTable),1);
                end

                if ~any(ismember(app.exStruct.waves.waveTable.Properties.VariableNames,'SplitFlag'))
                    app.exStruct.waves.waveTable.SplitFlag = zeros(height(app.exStruct.waves.waveTable),1);
                end

                if ~any(ismember(app.exStruct.waves.waveTable.Properties.VariableNames,'MergeFlag'))
                    app.exStruct.waves.waveTable.MergeFlag = zeros(height(app.exStruct.waves.waveTable),1);
                end

                loadImages(app);

                % intial slider setup
                app.Slider.Limits = [1 length(exStruct.xyShifts)];
                app.Slider.Value = 1;
                app.SliderLabel.Text = f(1:end-13);

                % bring to front
                figure(app.UIFigure);
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            frame = round(app.Slider.Value); % integer round

            app.Slider.Value = frame; % set

            setImagesSlider(app, frame);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key

                case 'rightarrow' % increase frame number

                    frame = round(app.Slider.Value); % integer round
                    newVal = frame +1; % set

                    if newVal >app.Slider.Limits(2)
                        newVal = app.Slider.Limits(2);
                    end

                    setImagesSlider(app, newVal);

                case 'leftarrow' % decrease frame number

                    frame = round(app.Slider.Value); % integer round
                    newVal = frame -1; % set

                    if newVal < app.Slider.Limits(1)
                        newVal = app.Slider.Limits(1);
                    end

                    setImagesSlider(app, newVal)

                case 'space' % clear selection
                    app.exStruct.waves.waveTable.DeleteFlag = zeros(size(app.exStruct.waves.waveTable.DeleteFlag));
                    app.exStruct.waves.waveTable.SplitFlag = zeros(size(app.exStruct.waves.waveTable.SplitFlag));
                    app.exStruct.waves.waveTable.MergeFlag = zeros(size(app.exStruct.waves.waveTable.MergeFlag));

                    plotDeleteBB(app);
                    plotSplitBB(app);
                    plotMergeBB(app);

            end

        end

        % Window scroll wheel function: UIFigure
        function ScrollWheelCallbck(app, event)
            %             verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;

            frame = round(app.Slider.Value); % integer round
            newVal = frame  + verticalScrollCount; % set

            if newVal < app.Slider.Limits(1)
                newVal = app.Slider.Limits(1);
            elseif newVal >app.Slider.Limits(2)
                newVal = app.Slider.Limits(2);
            end

            setImagesSlider(app, newVal)

        end

        % Button down function: imageWaveColAx
        function imageWaveColAxButtonDown(app, event)
            clickPt = round(event.IntersectionPoint(1:2));

            % match wave object
            cellIndx = findClosestObj(app,clickPt);

            cntrlDw =  getAsyncKeyState(VirtualKeyCode.VK_CONTROL);
            atlDw = getAsyncKeyState(VirtualKeyCode.VK_MENU);

            if cntrlDw == 0 && atlDw == 0

                % delete ROIs
                switch event.Button

                    case 1
                        findDeleteIndxStart(app,cellIndx, event.Button);

                    case 3
                        findDeleteIndxEnd(app,cellIndx, event.Button);
                end

                % split ROI off into new wave
            elseif cntrlDw == 1 && atlDw == 0
                switch event.Button

                    case 1
                        findSplitIndxStart(app,cellIndx);

                    case 3
                        findSplitIndxEnd(app,cellIndx);

                end

                % merge ROI
            elseif cntrlDw == 0 && atlDw == 1
                switch event.Button

                    case 1
                        findMergeIndxStart(app,cellIndx);

                    case 3
                        findMergeIndxEnd(app,cellIndx);

                end
            end

            clear atlDw cntrlDw
        end

        % Button down function: imageDFAx
        function imageDFAxButtonDown(app, event)
            clickPt = round(event.IntersectionPoint(1:2));

            % match wave object
            cellIndx = findClosestObj(app,clickPt);

            cntrlDw =  getAsyncKeyState(VirtualKeyCode.VK_CONTROL);
            atlDw = getAsyncKeyState(VirtualKeyCode.VK_MENU);

            if cntrlDw == 0 && atlDw == 0

                switch event.Button

                    case 1
                        findDeleteIndxStart(app,cellIndx, event.Button);

                    case 3
                        findDeleteIndxEnd(app,cellIndx, event.Button);
                end
            elseif cntrlDw == 1 && atlDw == 0
                switch event.Button

                    case 1
                        findSplitIndxStart(app,cellIndx);

                    case 3
                        findSplitIndxEnd(app,cellIndx);

                end

            elseif cntrlDw == 0 && atlDw == 1
                switch event.Button

                    case 1
                        findMergeIndxStart(app,cellIndx);

                    case 3
                        findMergeIndxEnd(app,cellIndx);

                end
            end
        end

        % Button pushed function: saveRecalcButton
        function saveRecalcButtonPushed(app, event)

            % remove deletes
            app.exStruct.waves.waveTable = app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.DeleteFlag == 0,:);

            %% get waves affected by split

            maxWaveNo = max(app.exStruct.waves.waveTable.waveNumber);
            splitObjs = app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.SplitFlag == 1,:);
            waveAffected = unique(splitObjs.waveNumber);

            % renumber wave splits
            count = 1;
            for w = 1:length(waveAffected)

                wav = waveAffected(w);
                % if wave split flag == 1 and wave number is correct
                app.exStruct.waves.waveTable.waveNumber(app.exStruct.waves.waveTable.SplitFlag == 1 & app.exStruct.waves.waveTable.waveNumber == wav) = maxWaveNo + count;
                count = count +1;
            end

            app.exStruct.waves.waveTable.SplitFlag = zeros(height( app.exStruct.waves.waveTable),1);

            %% deal with merges
            mergeWaveObj = app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.MergeFlag == 1,:);
            minWaveNo = min(mergeWaveObj.waveNumber);

            if ~isempty(minWaveNo)

                mergeWaveObj.waveNumber = minWaveNo * ones(height(mergeWaveObj),1);
                mergeWaveObj.MergeFlag = zeros(height(mergeWaveObj),1);

                app.exStruct.waves.waveTable(app.exStruct.waves.waveTable.MergeFlag == 1,:) = mergeWaveObj;

            end

            %% renumber waves
            [~,~,newWaveNo] = unique(app.exStruct.waves.waveTable.waveNumber);
            app.exStruct.waves.waveTable.waveNumber = newWaveNo;

            [app.exStruct.waves, newWaveColStack] = recalculateWaveMetrics(app);

            app.disStack.waveColStack = newWaveColStack;

            exStruct = app.exStruct;

            save([app.exStruct.filePath(1:end-4) '_ExStruct.mat'], 'exStruct', '-v7.3');

            frame = round(app.Slider.Value); % integer round
            setImagesSlider(app, frame);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @ScrollWheelCallbck, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.imageDFAx = uiaxes(app.UIFigure);
            app.imageDFAx.XLim = [0 512];
            app.imageDFAx.YLim = [0 512];
            app.imageDFAx.XTick = [];
            app.imageDFAx.YTick = [];
            app.imageDFAx.ZTick = [];
            app.imageDFAx.ButtonDownFcn = createCallbackFcn(app, @imageDFAxButtonDown, true);
            colormap(app.imageDFAx, 'gray')
            app.imageWaveColAx = uiaxes(app.UIFigure);
            app.imageWaveColAx.XLim = [0 512];
            app.imageWaveColAx.YLim = [0 512];
            app.imageWaveColAx.XTick = [];
            app.imageWaveColAx.YTick = [];
            app.imageWaveColAx.ZTick = [];
            app.imageWaveColAx.ButtonDownFcn = createCallbackFcn(app, @imageWaveColAxButtonDown, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load in Experiment Structure';
            app.saveRecalcButton = uibutton(app.UIFigure, 'push');
            app.saveRecalcButton.ButtonPushedFcn = createCallbackFcn(app, @saveRecalcButtonPushed, true);
            app.saveRecalcButton.Text = 'Recalculate and save Ex Structure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waveTrackingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end